classdef movable_port_arduDAQ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PortButton                      matlab.ui.control.Button
        TTLstatusLamp                   matlab.ui.control.Lamp
        TTLstatusLampLabel              matlab.ui.control.Label
        DAQARDUListBox                  matlab.ui.control.ListBox
        ARDUDAQLabel                    matlab.ui.control.Label
        ARDUdaqConnectButton            matlab.ui.control.Button
        repSwitch                       matlab.ui.control.Label
        RepetitionLabel                 matlab.ui.control.Label
        DAQTab                          matlab.ui.container.TabGroup
        taskparamTab                    matlab.ui.container.Tab
        DisconnectmotoraddtrialforrandomsessionLabel  matlab.ui.control.Label
        SharedLabel                     matlab.ui.control.Label
        GNGVolumeEditField              matlab.ui.control.EditField
        VolumeLabel                     matlab.ui.control.Label
        CCAirpuffProb_2                 matlab.ui.control.EditField
        AirpuffprobLabel_2              matlab.ui.control.Label
        TotaltrialNumEditField          matlab.ui.control.EditField
        TotaltrialNumEditFieldLabel     matlab.ui.control.Label
        CCAirpuffProb                   matlab.ui.control.EditField
        AirpuffprobLabel                matlab.ui.control.Label
        GNGTimeoutsEditField            matlab.ui.control.EditField
        TimeoutsEditFieldLabel          matlab.ui.control.Label
        GONOGOLabel                     matlab.ui.control.Label
        GNGReinforcerdelaysEditField    matlab.ui.control.EditField
        ReinforcerdelaysEditFieldLabel  matlab.ui.control.Label
        GNGLickwindowsEditField         matlab.ui.control.EditField
        LickwindowsEditFieldLabel       matlab.ui.control.Label
        airpuffDuration                 matlab.ui.control.EditField
        AirpuffdurationsLabel           matlab.ui.control.Label
        ClassicalConditioningLabel      matlab.ui.control.Label
        PortDelaysEditField             matlab.ui.control.EditField
        PortDelaysEditFieldLabel        matlab.ui.control.Label
        ReinforcerProbabilityEditField  matlab.ui.control.EditField
        ReinforcerprobLabel             matlab.ui.control.Label
        WatersizeLEditField             matlab.ui.control.EditField
        WatersizeLEditFieldLabel        matlab.ui.control.Label
        StimFreqkHzEditField            matlab.ui.control.EditField
        StimFreqkHzEditFieldLabel       matlab.ui.control.Label
        CCUSDelaysEditField             matlab.ui.control.EditField
        USDelaysEditFieldLabel          matlab.ui.control.Label
        ITIsEditField                   matlab.ui.control.EditField
        ITIsEditFieldLabel              matlab.ui.control.Label
        RespdurationsEditField          matlab.ui.control.EditField
        RespdurationsEditFieldLabel     matlab.ui.control.Label
        StimdurationsEditField          matlab.ui.control.EditField
        StimdurationsEditFieldLabel     matlab.ui.control.Label
        ISIsEditField                   matlab.ui.control.EditField
        ISIsEditFieldLabel              matlab.ui.control.Label
        StartLEDdursEditField           matlab.ui.control.EditField
        StartLEDdursEditFieldLabel      matlab.ui.control.Label
        NIDaqTab                        matlab.ui.container.Tab
        NIDAQRecLamp                    matlab.ui.control.Lamp
        NIDAQnotrecordingLabel          matlab.ui.control.Label
        FindNIButton                    matlab.ui.control.Button
        RecordingonNIDAQCheckBox        matlab.ui.control.CheckBox
        NIDAQListBox                    matlab.ui.control.ListBox
        NIDAQLabel                      matlab.ui.control.Label
        AI7EditField                    matlab.ui.control.EditField
        AI7EditFieldLabel               matlab.ui.control.Label
        AI6EditField                    matlab.ui.control.EditField
        AI6EditFieldLabel               matlab.ui.control.Label
        AI5EditField                    matlab.ui.control.EditField
        AI5EditFieldLabel               matlab.ui.control.Label
        AI4EditField                    matlab.ui.control.EditField
        AI4EditFieldLabel               matlab.ui.control.Label
        AI3EditField                    matlab.ui.control.EditField
        AI3EditFieldLabel               matlab.ui.control.Label
        AI2EditField                    matlab.ui.control.EditField
        AI2EditFieldLabel               matlab.ui.control.Label
        AI1EditField                    matlab.ui.control.EditField
        AI1EditFieldLabel               matlab.ui.control.Label
        AI0EditField                    matlab.ui.control.EditField
        AI0EditFieldLabel               matlab.ui.control.Label
        NIDAQRecordingTTLLamp           matlab.ui.control.Lamp
        NotrecordingLabel               matlab.ui.control.Label
        NIRangeVEditField               matlab.ui.control.EditField
        RangeVEditField_3Label          matlab.ui.control.Label
        SampleRateHzEditField_3         matlab.ui.control.EditField
        SampleRateHzEditField_3Label    matlab.ui.control.Label
        NIDAQInputChannelsEditField     matlab.ui.control.EditField
        InputChannelsEditField_3Label   matlab.ui.control.Label
        NIdaqConnectButton              matlab.ui.control.Button
        NIDAQPINsLabel                  matlab.ui.control.Label
        pinandwatercalibrationTab       matlab.ui.container.Tab
        ControlARDUPinInfoifchangehavetomodifyondeviceLabel  matlab.ui.control.Label
        WatercalibrationLabel           matlab.ui.control.Label
        ofdatapointsEditField           matlab.ui.control.EditField
        ofdatapointsEditFieldLabel      matlab.ui.control.Label
        ValveOpensEditField             matlab.ui.control.EditField
        ValveOpensEditFieldLabel        matlab.ui.control.Label
        RunCalibrationButton            matlab.ui.control.Button
        AifpuffdaqEditField             matlab.ui.control.EditField
        AifpuffdaqEditFieldLabel        matlab.ui.control.Label
        WaterDaqEditField               matlab.ui.control.EditField
        WaterDaqEditFieldLabel          matlab.ui.control.Label
        MotorDaqEditField               matlab.ui.control.EditField
        MotorDaqEditFieldLabel          matlab.ui.control.Label
        LEDDaqEditField                 matlab.ui.control.EditField
        LEDDaqEditFieldLabel            matlab.ui.control.Label
        AirpuffEditField                matlab.ui.control.EditField
        AirpuffEditFieldLabel           matlab.ui.control.Label
        LEDEditField                    matlab.ui.control.EditField
        LEDEditFieldLabel               matlab.ui.control.Label
        MotorEditField                  matlab.ui.control.EditField
        MotorEditFieldLabel             matlab.ui.control.Label
        WaterEditField                  matlab.ui.control.EditField
        WaterEditFieldLabel             matlab.ui.control.Label
        LickSensorInputEditField        matlab.ui.control.EditField
        LickSensorInputEditFieldLabel   matlab.ui.control.Label
        SoundDaqEditField               matlab.ui.control.EditField
        SoundDaqEditFieldLabel          matlab.ui.control.Label
        Cam_pupilTab                    matlab.ui.container.Tab
        ARDUDaqInfoTab                  matlab.ui.container.Tab
        ARDUdaqSwtich                   matlab.ui.control.Switch
        EditField_20                    matlab.ui.control.EditField
        Label_10                        matlab.ui.control.Label
        EditField_19                    matlab.ui.control.EditField
        Label_9                         matlab.ui.control.Label
        EditField_18                    matlab.ui.control.EditField
        Label_12                        matlab.ui.control.Label
        EditField_17                    matlab.ui.control.EditField
        Label_11                        matlab.ui.control.Label
        EditField_16                    matlab.ui.control.EditField
        EditField_16Label               matlab.ui.control.Label
        EditField_15                    matlab.ui.control.EditField
        EditField_15Label               matlab.ui.control.Label
        EditField_14                    matlab.ui.control.EditField
        EditField_14Label               matlab.ui.control.Label
        EditField_13                    matlab.ui.control.EditField
        EditField_13Label               matlab.ui.control.Label
        EditField_12                    matlab.ui.control.EditField
        EditField_12Label               matlab.ui.control.Label
        EditField_11                    matlab.ui.control.EditField
        EditField_11Label               matlab.ui.control.Label
        EditField_9                     matlab.ui.control.EditField
        Label_7                         matlab.ui.control.Label
        BaudRateHzEditField             matlab.ui.control.EditField
        BaudRateHzEditFieldLabel        matlab.ui.control.Label
        ARDUDAQInputChannelsEditField   matlab.ui.control.EditField
        InputChannelsEditField_2Label   matlab.ui.control.Label
        ArduinoDigitalIOPINsifchangehavetomodifyondeviceLabel  matlab.ui.control.Label
        AD3DaqTab                       matlab.ui.container.Tab
        AD3DAQListBox                   matlab.ui.control.ListBox
        DAQListBoxLabel                 matlab.ui.control.Label
        ADdaqConnectButton              matlab.ui.control.Button
        AD3daqSwtich                    matlab.ui.control.Switch
        AD3DigitalPINsLabel             matlab.ui.control.Label
        EditField_8                     matlab.ui.control.EditField
        EditField_8Label                matlab.ui.control.Label
        EditField_7                     matlab.ui.control.EditField
        EditField_7Label                matlab.ui.control.Label
        EditField_6                     matlab.ui.control.EditField
        EditField_6Label                matlab.ui.control.Label
        EditField_5                     matlab.ui.control.EditField
        EditField_5Label                matlab.ui.control.Label
        EditField_4                     matlab.ui.control.EditField
        EditField_4Label                matlab.ui.control.Label
        EditField_3                     matlab.ui.control.EditField
        EditField_3Label                matlab.ui.control.Label
        EditField_2                     matlab.ui.control.EditField
        EditField_2Label                matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        EditFieldLabel                  matlab.ui.control.Label
        RecordingTTLLamp                matlab.ui.control.Lamp
        RecordingTTLLampLabel           matlab.ui.control.Label
        RangeVEditField                 matlab.ui.control.EditField
        RangeVLabel                     matlab.ui.control.Label
        SampleRateHzEditField           matlab.ui.control.EditField
        SampRateHzLabel                 matlab.ui.control.Label
        ADInputChannelsEditField        matlab.ui.control.EditField
        InputChannelsEditFieldLabel     matlab.ui.control.Label
        SparepartsTab                   matlab.ui.container.Tab
        ExptypeEditField                matlab.ui.control.EditField
        ExptypeEditFieldLabel           matlab.ui.control.Label
        AnimaltypeEditField             matlab.ui.control.EditField
        AnimaltypeEditFieldLabel        matlab.ui.control.Label
        ExperimenterEditFieldLabel      matlab.ui.control.Label
        ExperimenterEditField           matlab.ui.control.EditField
        RigEditField                    matlab.ui.control.EditField
        RigEditFieldLabel               matlab.ui.control.Label
        P07EditField                    matlab.ui.control.EditField
        P07EditFieldLabel               matlab.ui.control.Label
        P06EditField                    matlab.ui.control.EditField
        P06EditFieldLabel               matlab.ui.control.Label
        P05EditField                    matlab.ui.control.EditField
        P05EditFieldLabel               matlab.ui.control.Label
        P04EditField                    matlab.ui.control.EditField
        P04EditFieldLabel               matlab.ui.control.Label
        P03EditField                    matlab.ui.control.EditField
        P03EditFieldLabel               matlab.ui.control.Label
        P02EditField                    matlab.ui.control.EditField
        P02EditFieldLabel               matlab.ui.control.Label
        P01EditField                    matlab.ui.control.EditField
        P01EditFieldLabel               matlab.ui.control.Label
        P00EditField                    matlab.ui.control.EditField
        P00EditFieldLabel               matlab.ui.control.Label
        ReSelectsavingfolderButton      matlab.ui.control.Button
        TaskstoppedLamp                 matlab.ui.control.Lamp
        TaskstoppedLampLabel            matlab.ui.control.Label
        RunSwitch                       matlab.ui.control.Switch
        TabGroup                        matlab.ui.container.TabGroup
        sessioninfoTab                  matlab.ui.container.Tab
        ExptypeDropDown                 matlab.ui.control.DropDown
        ExptypeDropDownLabel            matlab.ui.control.Label
        AnimaltypeDropDown              matlab.ui.control.DropDown
        AnimaltypeDropDownLabel         matlab.ui.control.Label
        ExperimenterDropDown            matlab.ui.control.DropDown
        ExperimenterDropDownLabel       matlab.ui.control.Label
        RigDropDown                     matlab.ui.control.DropDown
        RigDropDownLabel                matlab.ui.control.Label
        MotorconnectedCheckBox          matlab.ui.control.CheckBox
        MotorDirectionwilloverwriteSwitch  matlab.ui.control.Switch
        MotorDirectionwilloverwriteSwitchLabel  matlab.ui.control.Label
        AnimalIDEditField               matlab.ui.control.EditField
        AnimalIDEditFieldLabel          matlab.ui.control.Label
        initTaskTypeSwitch              matlab.ui.control.Switch
        TaskListBox                     matlab.ui.control.ListBox
        TaskListBoxLabel                matlab.ui.control.Label
        ControlARDUListBox              matlab.ui.control.ListBox
        ControlARDUListBoxLabel         matlab.ui.control.Label
        GUItitle                        matlab.ui.control.Label
        DurationEditField               matlab.ui.control.EditField
        DurationEditFieldLabel          matlab.ui.control.Label
        StopEditField                   matlab.ui.control.EditField
        StopEditFieldLabel              matlab.ui.control.Label
        StartEditField                  matlab.ui.control.EditField
        StartEditFieldLabel             matlab.ui.control.Label
        LogTextArea                     matlab.ui.control.TextArea
        LogTextAreaLabel                matlab.ui.control.Label
        dprime                          matlab.ui.control.Label
        DprimeLabel                     matlab.ui.control.Label
        status                          matlab.ui.control.Label
        StateLabel                      matlab.ui.control.Label
        accuracy                        matlab.ui.control.Label
        AccuracyLabel                   matlab.ui.control.Label
        NlickRate                       matlab.ui.control.Label
        NCSlicksLabel                   matlab.ui.control.Label
        RlickRate                       matlab.ui.control.Label
        RCSlicksLabel                   matlab.ui.control.Label
        trialNum                        matlab.ui.control.Label
        TrialLabel                      matlab.ui.control.Label
        AirpuffButton                   matlab.ui.control.Button
        WaterButton                     matlab.ui.control.Button
        ConnectButton_4                 matlab.ui.control.Button
        bigPlot                         matlab.ui.control.UIAxes
        smallPlot                       matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Info % Description
        CC
        GNG
        DAQ
        CalibrationParam
        ADInput
        Stim
        NIDAQ
        NIDAQ_ai
    end
    
    methods (Access = private)
        %% Water calibration: release  water + get weight differences
        function [diff_weight] = calibration(app)
            opening_durs = app.CalibrationParam.calibTime;
            diff_weight = nan(1, length(opening_durs));

            for idx = 1:length(opening_durs) % usually 3
                % generate text for input and get the inputed data
                initial_weight = WeightInputWindow(app,'Initial');
                % Displaying the process in the log text area
                app.LogTextArea.Value{end+1} = ...
                    ['calibrating the port by ',num2str(app.CalibrationParam.calibTime(idx)),' sec'];
                scroll(app.LogTextArea, 'bottom');
                pause(3);

                % water dispense
                waterRelease(app, idx);

                % generate text for input and get the inputed data
                after_weight = WeightInputWindow(app,'Final');
                diff_weight(idx) = after_weight - initial_weight;
            end
        end

        function waterRelease(app, idx) % removed the side info from function
            nRelease = app.CalibrationParam.nRelease;
            for eachOpening = 1:nRelease
                writeDigitalPin(app.Info.ARDU, app.Info.PIN.water, 1); 
                % wait for water to be dispensed
                pause(app.CalibrationParam.calibTime(idx));
                writeDigitalPin(app.Info.ARDU, app.Info.PIN.water, 0);
                pause(0.5);
            end
        end
        
        %% Weight input 
        function [weight] = WeightInputWindow(app, prompt) % get calibration data from input windows
            prompt_full = {[prompt, 'Weight [g]']};
            dlgtitle = 'Please Input'; %title of the dialog box
            fieldsize = [1 35]; % size of the input field
            definput = {''};
            weight_input = inputdlg(prompt_full, dlgtitle, fieldsize, definput); % generate an input dialog box
            % answer = inputdlg(prompt,dlgtitle,fieldsize,definput
            weight = str2double(weight_input{1}); % get the inputed data            
        end
        
        %% Draw weights + save fitted slope to app.CalibrationParam.slope
        function drawWeights(app, diff_weight) 
            nRelease = app.CalibrationParam.nRelease;
            opening_durs = app.CalibrationParam.calibTime;
            ave_weight = diff_weight/nRelease; % same length as opening_durs

            figure();            
            scatter(opening_durs, ave_weight, 'Marker','o','MarkerEdgeColor','#336B87');
            model = fitglm([opening_durs 0]',[ave_weight 0]','Intercept',false);
            slope = model.Coefficients.Estimate;
            app.CalibrationParam.slope = slope;
            yhat = slope*(max(opening_durs)+0.05);
            hold on;
            plot([0 max(opening_durs)+0.05],[0 yhat]);
            xlabel('Solenoid open time [s]');
            ylabel('Water amount [ml]');
            title('Water Solenoid Calibration');
            hold off;
        end
        %% Calculated TTL based on reward size and slope and save TTL
        function saveWaterTTLTimeInfo(app, diff_weight)
            slope = app.CalibrationParam.slope; % assigning app.CalibrationParam.slope
            rewardSize = app.CC.taskparam.waterSize;
            openTime = rewardSize / slope;
            app.Info.taskparam.waterTTLTime = openTime/1000;

            xlsxpath = app.Info.path.waterCalibrationRecord;
            sheet = app.Info.session.rig;
            
            % Read the existing data from the sheet
            existingData = readtable(xlsxpath, 'Sheet', sheet, 'VariableNamingRule', 'preserve');
            fields = existingData.Properties.VariableNames;
       
            % Values for the new row
            time = string(datetime('now', 'Format', 'yyyy-MM-dd HH:mm'));

            valveOpenTime = num2str(app.CalibrationParam.calibTime);
            nRelease = app.CalibrationParam.nRelease;
        
            % Create a table for the new values with the correct side fields
            newRow = table(time, {num2str(diff_weight)}, slope, openTime, ...
                                 rewardSize, {valveOpenTime}, nRelease, ...
                                 'VariableNames', fields);
            % Date|Weight Differences (g)|Fitted Slope (ml/s)|Open Time Used (s)|Reward Size (ml)|Valve Open Time (s)|nRelease														
            
            % Concatenate the new row to the existing data
            updatedData = [existingData; newRow];
        
            % Write back to the Excel sheet
            writetable(updatedData, xlsxpath, 'Sheet', sheet, 'WriteMode', 'overwritesheet');
        end

        function loadWaterTTL(app)
            xlsxpath = app.Info.path.waterCalibrationRecord;
            sheet = app.Info.session.rig;

            % Read the data from the Excel file
            data = readtable(xlsxpath, 'Sheet', sheet, 'VariableNamingRule', 'preserve');
            
            % Find the column index for the field name "Fitted Slope (ml/s)"
            fields = data.Properties.VariableNames;
            slopeColIdx = strcmp(fields, 'Fitted Slope (ml/s)');
            try
                slope = data{end, slopeColIdx};
                app.CalibrationParam.slope = slope;
                app.CC.taskparam.waterTTLTime = app.CC.taskparam.waterSize / (slope*1000);
                app.GNG.taskparam.waterTTLTime = app.CC.taskparam.waterSize / (slope*1000);
                app.LogTextArea.Value{end+1} = 'Water TTL successfully updated.';
                scroll(app.LogTextArea, 'bottom');
            catch ME
                confirm = msgbox('No valid slope data found. Please recalibrate.', 'Warning');
                uiwait(confirm);
                app.LogTextArea.Value{end+1} = 'No record found. You have to CALIBRATE!';
                scroll(app.LogTextArea, 'bottom');
            end
        end

        function loadAvailableSerialports(app, boxName, availableCOM)
            % display available serial ports for both ARDU and daq ARDU
            app.(boxName).Items = availableCOM;

        end 

        function saveNIDaqTTL(app)
            stop(app.NIDAQ_ai);

            app.NIDAQRecordingTTLLamp.Color = [0.85,0.33,0.10];
            % app.NIDAQRecordingTTLLampLabel.Text = 'NI DAQ stopped';
            % app.NIDAQRecordingTTLLampLabel.FontColor = [0.85,0.33,0.10];

            session_info = [app.Info.session.date, app.Info.session.animalID, app.Info.session.taskName(1:2)];

            % load last saved session data
            dataFolder = dir(app.Info.path.data);
            sessionFolders = dataFolder([dataFolder.isdir] & ~ismember({dataFolder.name}, {'.', '..'}));
            [~, ~] = sort({sessionFolders.name});
            lastSessionFolder_name = sessionFolders(end).name;
            lastSessionFolder_path = fullfile(sessionFolders(end).folder, lastSessionFolder_name);
            date = lastSessionFolder_name(1:10);
            animalID = lastSessionFolder_name(15:18);
            taskName = lastSessionFolder_name(20:21);
            check = [strcmp(date, session_info(1)), strcmp(animalID, session_info(2)), strcmp(taskName, session_info(3))];
            
            if ~isempty(sessionFolders(end))
                if all(check)
                    % if last session folder is not empty and the name contains all the session info 
                    % read the session struct from the .mat file
                    disp(['The last folder in the Data dir is: ', lastSessionFolder_name])
                    disp('Now opening')
                    matPath = [fullfile(lastSessionFolder_path, lastSessionFolder_name) '.mat'];
                    matFile = load(matPath);
                    session = matFile.session;
                    session.NIDAQ = app.NIDAQ;
                else
                    disp("Last folder name doesn't match current session info. Creating a .mat file in the Data-misc folder.")
                    nameMain = strcat(app.Info.session.date,'-', app.Info.session.animalID, '-', app.Info.session.taskName, '-NITTL.mat');
                    matPath = fullfile(app.Info.path.mother, 'Data-misc', nameMain);
                    session = struct();
                end
            else
                disp('The last folder in the Data directory is empty. Creating a .mat file in the Data-misc folder.')
                nameMain = strcat(app.Info.session.date,'-', app.Info.session.animalID, '-', app.Info.session.taskName, '-NITTL.mat');
                matPath = fullfile(app.Info.path.mother, 'Data-misc', nameMain);
                session = struct();
            end

            % store the timestamps
            nidaqRead = read(app.NIDAQ_ai, app.NIDAQ_ai.NumScansAvailable);
            session.NIDAQ.raw = nidaqRead;
            try
                session.NIDAQ.TTL = nihelper.getTTL(nidaqRead, app.NIDAQ.channelNames);
                session.NIDAQ.TTL_yuma = nihelper.getTTL_yuma(nidaqRead, app.NIDAQ.channelNames);
            catch ME
                app.LogTextArea.Value{end+1} = 'NI helper script not working';
                scroll(app.LogTextArea, 'bottom');
            end

            % saving 
            save(matPath, 'session','-v7.3');
            % app.NIDAQRecordingTTLLampLabel.Text = 'NI DAQ data saved';
            disp('NI DAQ data saved');
            clear session timestamps data nidaqRead;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            pause(0.5);

            selected_path = 0 ;
            while selected_path == 0
                selected_path = uigetdir('C:\', 'Please select the project folder!');
            end

            app.Info.path.mother = selected_path;
            app.Info.path.task = fullfile(app.Info.path.mother,'+task');
            task = dir(app.Info.path.task); %%%%% where the task folder is defined
            task = task(contains({task.name}, {'.m'}));
            task = {task.name}; 

            for i = 1:length(task)
                task{i}(end-1:end) = [];
            end

            %% Display task options 
            app.TaskListBox.Items = task;

            %% Display available serial ports for both ARDU and daq ARDU
            availableCOM = serialportlist("available");
            loadAvailableSerialports(app, 'ControlARDUListBox', availableCOM);
            app.Info.ARDU_COM = availableCOM(1);
            loadAvailableSerialports(app, 'DAQARDUListBox', availableCOM);
            app.DAQ.ARDU_COM = availableCOM(1);

            %% DATE update
            % datetime.setDefaultFormats('default','yyyy-MM-dd hh:mm:ss')
            appStartTime = datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss');
            tmp = char(appStartTime);
            app.LogTextArea.Value{end+1} =  ['Opened GUI at ',tmp];
            scroll(app.LogTextArea, 'bottom');
          
            %% Task param setting 
            % CC task parameter setting
            app.CC.taskparam.startCueDur = str2double(app.StartLEDdursEditField.Value);
            app.CC.taskparam.isi = str2double(app.ISIsEditField.Value);
            app.CC.taskparam.stimDur = str2double(app.StimdurationsEditField.Value);
            app.CC.taskparam.portDelay = str2double(app.PortDelaysEditField.Value);
            app.CC.taskparam.fusDelay = str2double(app.CCUSDelaysEditField.Value);
            app.CC.taskparam.respDur = str2double(app.RespdurationsEditField.Value);
            app.CC.taskparam.USprobability = str2double(app.ReinforcerProbabilityEditField.Value);
            app.CC.taskparam.airpuffProbability = str2double(app.CCAirpuffProb.Value);

            app.CC.taskparam.waterSize = str2double(app.WatersizeLEditField.Value);
            app.CC.taskparam.iti = str2num(app.ITIsEditField.Value);
            app.CC.taskparam.stimFreq = str2num(app.StimFreqkHzEditField.Value);
            app.CC.taskparam.airpuffDur = str2double(app.airpuffDuration.Value);
            app.CC.taskparam.waterTTLTime = 'n/a';

            % GNG task parameter setting
            app.GNG.taskparam.startCueDur = str2double(app.StartLEDdursEditField.Value);
            app.GNG.taskparam.isi = str2double(app.ISIsEditField.Value);
            app.GNG.taskparam.stimDur = str2double(app.StimdurationsEditField.Value);
            app.GNG.taskparam.portDelay = str2double(app.PortDelaysEditField.Value);
            app.GNG.taskparam.lickWindow = str2double(app.GNGLickwindowsEditField.Value);
            app.GNG.taskparam.reinforcerDelay = str2double(app.GNGReinforcerdelaysEditField.Value);
            app.GNG.taskparam.respDur = str2double(app.RespdurationsEditField.Value);
            app.GNG.taskparam.timeout = str2double(app.GNGTimeoutsEditField.Value);
            app.GNG.taskparam.reinforcerProb = str2double(app.ReinforcerProbabilityEditField.Value);
            app.GNG.taskparam.volume = str2num(app.GNGVolumeEditField.Value);

            app.GNG.taskparam.waterSize = str2double(app.WatersizeLEditField.Value);
            app.GNG.taskparam.iti = str2num(app.ITIsEditField.Value);
            app.GNG.taskparam.stimFreq = str2num(app.StimFreqkHzEditField.Value);
            app.GNG.taskparam.airpuffDur = str2double(app.airpuffDuration.Value);
            app.GNG.taskparam.waterTTLTime = 'n/a';

            %% Experiment params setting
            app.Info.session.computerName = [getenv('username'),'-',computer];
            app.Info.session.date = char(datetime('now','Format','yyyy-MM-dd'));
            app.Info.session.animalID = app.AnimalIDEditField.Value;
            value = app.RigEditField.Value;
            if all(isstrprop(value, 'digit'))
                app.Info.session.rig = ['rig', value];
            else
                app.Info.session.rig = value;
            end
            app.Info.session.experimenter = app.ExperimenterDropDown.Value;
            app.Info.session.expType = app.ExptypeDropDown.Value;
            app.Info.session.animalType = app.AnimaltypeDropDown.Value;
            app.Info.session.rule = app.initTaskTypeSwitch.Value;
            app.Info.session.motorConnection = app.MotorconnectedCheckBox.Value;
            app.Info.session.daqrec = true;
            app.Info.session.name = 'n/a';
            app.Info.session.ID = 'n/a';
            app.Info.session.recording = 'n/a';
            app.Info.session.taskName = app.TaskListBox.Value;
            app.Info.session.prespecifiedTrialNum = str2double(app.TotaltrialNumEditField.Value);

            %% Path setting: app.Info.path..
            app.Info.path.mother = selected_path;
            app.Info.path.task = fullfile(app.Info.path.mother, '+task');
            app.Info.path.waterCalibration = fullfile(app.Info.path.mother,'+waterCalibration');
            app.Info.path.waterCalibrationRecord = fullfile(app.Info.path.waterCalibration, 'waterCalibrationRecordEphys.xlsx');
            app.Info.path.data = fullfile(app.Info.path.mother, 'Data');
            app.Info.path.animalData = fullfile(app.Info.path.mother, 'Data-sortByAnimal');
            app.Info.path.dataSession = 'undefined yet';
            
            %% Pin information setting for control ARDU
            app.Info.PIN.LED = app.LEDEditField.Value;
            app.Info.PIN.LEDDaq = app.LEDDaqEditField.Value;
            app.Info.PIN.soundDaq = app.SoundDaqEditField.Value; 
            app.Info.PIN.motor = app.MotorEditField.Value;
            app.Info.PIN.motorDaq = app.MotorDaqEditField.Value;
            app.Info.PIN.water = app.WaterEditField.Value; 
            app.Info.PIN.waterDaq = app.WaterDaqEditField.Value;
            app.Info.PIN.airpuff = app.AirpuffEditField.Value;
            app.Info.PIN.airpuffDaq = app.AifpuffdaqEditField.Value;
            app.Info.PIN.lickSensor = app.LickSensorInputEditField.Value; 

            %% Calibration parameters: app.Info.CalibrationParam..
            app.CalibrationParam.calibTime = str2num(app.ValveOpensEditField.Value);
            app.CalibrationParam.nRelease = str2double(app.ofdatapointsEditField.Value);

            %% Auditory stim setting
            app.Stim.samplingFreq = 48000;
            app.Stim.toneFreq = app.CC.taskparam.stimFreq*1000;
            app.Stim.fade_duration = 10;
            
            %% SERVO motor setting
            value = app.MotorDirectionwilloverwriteSwitch.Value;
            switch value
                case 'Fwd'
                    app.Info.port.dir = 'fwd';
                    app.Info.port.approach = 0.2;
                    app.Info.port.withdraw = 0.4;
                case 'Rev'
                    app.Info.port.dir = 'rev';
                    app.Info.port.approach = 0.4;
                    app.Info.port.withdraw = 0.2;
            end

            %% ARDU DAQ settings
            app.DAQ.ARDU_baudRate = str2double(app.BaudRateHzEditField.Value);

            %% Assign water TTL at start up
            loadWaterTTL(app)
        end

        % Value changed function: ControlARDUListBox
        function ControlARDUListBoxValueChanged(app, event)
            app.Info.ARDU_COM = app.ControlARDUListBox.Value;
        end

        % Button pushed function: ConnectButton_4
        function ConnectButton_4Pushed(app, event)
            if isfield(app.Info,'ARDU')                
                app.LogTextArea.Value{end+1} = [char(app.Info.ARDU_COM), ' already Connected!! For deletion, reopen the app!!'];
                scroll(app.LogTextArea, 'bottom');
            end
                app.LogTextArea.Value{end+1} =  'Assigning arduino ...... wait for connection!';
                scroll(app.LogTextArea, 'bottom');

                % use servo library
                app.Info.ARDU = arduino(app.Info.ARDU_COM, 'Uno', 'Libraries', 'Servo', 'ForceBuildOn', true);

                % availableCOM = serialportlist("available");
                % loadAvailableSerialports(app, 'DAQARDUListBox', availableCOM);
                % app.DAQ.ARDU_COM = availableCOM(1);
                
                % write all digital pin to 0
                for i = [2:8 10:12]
                    pin = ['D', num2str(i)];
                    writeDigitalPin(app.Info.ARDU, pin, 0);
                end

                configurePin(app.Info.ARDU, app.Info.PIN.LED, 'DigitalOutput');
                configurePin(app.Info.ARDU, app.Info.PIN.LEDDaq, 'DigitalOutput');
                configurePin(app.Info.ARDU, app.Info.PIN.soundDaq, 'DigitalOutput');
                app.Info.servo = servo(app.Info.ARDU, app.Info.PIN.motor);
                configurePin(app.Info.ARDU, app.Info.PIN.motorDaq,'DigitalOutput');
                configurePin(app.Info.ARDU, app.Info.PIN.water, 'DigitalOutput');
                configurePin(app.Info.ARDU, app.Info.PIN.waterDaq,'DigitalOutput');
                configurePin(app.Info.ARDU,app.Info.PIN.airpuff,'DigitalOutput');
                configurePin(app.Info.ARDU, app.Info.PIN.airpuffDaq,'DigitalOutput');
                configurePin(app.Info.ARDU, app.Info.PIN.lickSensor, 'DigitalInput');

                % Move port forward
                writePosition(app.Info.servo, app.Info.port.approach); 
                app.Info.port.state = 'approach';

                tmp = char(app.Info.ARDU_COM);
                app.LogTextArea.Value{end+1} = char(['Connected to ', tmp]);
                scroll(app.LogTextArea, 'bottom');
        end

        % Value changed function: TaskListBox
        function TaskListBoxValueChanged(app, event)
            app.Info.session.taskName = app.TaskListBox.Value;
        end

        % Value changed function: ISIsEditField
        function ISIsEditFieldValueChanged(app, event)
            app.CC.taskparam.isi = str2double(app.ISIsEditField.Value);
            app.GNG.taskparam.isi = str2double(app.ISIsEditField.Value);
        end

        % Value changed function: StimdurationsEditField
        function StimdurationsEditFieldValueChanged(app, event)
            app.CC.taskparam.stimDur = str2double(app.StimdurationsEditField.Value);
            app.GNG.taskparam.stimDur = str2double(app.StimdurationsEditField.Value);
        end

        % Value changed function: RespdurationsEditField
        function RespdurationsEditFieldValueChanged(app, event)
            app.CC.taskparam.respDur = str2double(app.RespdurationsEditField.Value);
            app.GNG.taskparam.reinforcerDelay = str2double(pp.RespdurationsEditField.Value);
        end

        % Value changed function: ITIsEditField
        function ITIsEditFieldValueChanged(app, event)
            value = str2num(app.ITIsEditField.Value);
            app.CC.taskparam.iti = value;
            app.GNG.taskparam.iti = value;
        end

        % Value changed function: StimFreqkHzEditField
        function StimFreqkHzEditFieldValueChanged(app, event)
            % unit is in kHz
            value = str2num(app.StimFreqkHzEditField.Value);
            app.CC.taskparam.stimFreq = value;
            app.GNG.taskparam.stimFreq = value;
            app.Stim.toneFreq = value*1000;
        end

        % Value changed function: AnimalIDEditField
        function AnimalIDEditFieldValueChanged(app, event)
            app.Info.session.animalID = app.AnimalIDEditField.Value;
        end

        % Value changed function: ExperimenterEditField
        function ExperimenterEditFieldValueChanged(app, event)
            app.Info.session.experimenter = app.ExperimenterEditField.Value;
            switch app.Info.session.experimenter
                case 'JB'
                    tmp = 'Jungwon';
                    app.LogTextArea.Value{end+1} =  ['Hello ',tmp];
                case 'ZW'
                    tmp = 'Ziyu';
                    app.LogTextArea.Value{end+1} =  ['Hello ',tmp];
            end
            scroll(app.LogTextArea, 'bottom');
        end

        % Value changed function: ExptypeEditField
        function ExptypeEditFieldValueChanged(app, event)
            app.Info.session.expType = app.ExptypeEditField.Value;
        end

        % Value changed function: AnimaltypeEditField
        function AnimaltypeEditFieldValueChanged(app, event)
            app.Info.session.animalType = app.AnimaltypeEditField.Value; 
        end

        % Value changed function: SoundDaqEditField
        function SoundDaqEditFieldValueChanged(app, event)
            app.Info.PIN.soundDaq = app.SoundDaqEditField.Value;
        end

        % Value changed function: LickSensorInputEditField
        function LickSensorInputEditFieldValueChanged(app, event)
            app.Info.PIN.lickSensor = app.LickSensorInputEditField.Value;
            configurePin(app.Info.ARDU, app.Info.PIN.lickSensor, 'DigitalInput');
        end

        % Value changed function: WaterEditField
        function WaterEditFieldValueChanged(app, event)
            app.Info.PIN.water = app.WaterEditField.Value;
        end

        % Value changed function: MotorEditField
        function MotorEditFieldValueChanged(app, event)
            app.Info.PIN.motor = app.MotorEditField.Value;
            % creating a servo object: https://www.mathworks.com/help/supportpkg/arduinoio/ug/control-servo-motors.html
            app.Info.servo = servo(app.Info.ARDU, app.Info.PIN.motor);
        end

        % Value changed function: LEDEditField
        function LEDEditFieldValueChanged(app, event)
            app.Info.PIN.LED = app.LEDEditField.Value;
        end

        % Value changed function: AirpuffEditField
        function AirpuffEditFieldValueChanged(app, event)
            app.Info.PIN.airpuff = app.AirpuffEditField.Value;
        end

        % Value changed function: ValveOpensEditField
        function ValveOpensEditFieldValueChanged(app, event)
            app.CalibrationParam.calibTime = str2num(app.ValveOpensEditField.Value);
        end

        % Value changed function: ofdatapointsEditField
        function ofdatapointsEditFieldValueChanged(app, event)
            app.CalibrationParam.nRelease = str2double(app.ofdatapointsEditField.Value);
        end

        % Button pushed function: RunCalibrationButton
        function RunCalibrationButtonPushed(app, event)
            waterCaliRep = questdlg('Do you want to calibrate the valve?', ...
                'Water calibration','Yes','No','No'); %questdlg(quest,dlgtitle,btn1,btn2,defbtn)
            switch waterCaliRep
                case 'Yes'
                    diff_weight = calibration(app);
                    drawWeights(app, diff_weight); % calculate slope, update app.CalibrationParam.slope
                    saveWaterTTLTimeInfo(app, diff_weight); % save to xlsx
                    app.LogTextArea.Value{end+1} =  'Done calibrating. You can SELECT THE TASK and start now!';
                    scroll(app.LogTextArea, 'bottom');
                case 'No'
                    % If 'No', look for the last saved slope in the Excel file
                    xlsxpath = app.Info.path.waterCalibrationRecord;
                    sheet = app.Info.session.rig;

                    % Read the data from the Excel file
                    data = readtable(xlsxpath, 'Sheet', sheet, 'VariableNamingRule', 'preserve');
                    
                    % Find the column index for the field name "Fitted Slope (ml/s)"
                    fields = data.Properties.VariableNames;
                    slopeColIdx = strcmp(fields, 'Fitted Slope (ml/s)');
                    try
                        slope = data{end, slopeColIdx};
                        app.CalibrationParam.slope = slope;
                        app.Info.taskparam.waterTTLTime = app.CC.taskparam.waterSize / (slope*1000);
                        app.LogTextArea.Value{end+1} = 'Previous calibration record found. You can SELECT THE TASK and start now!';
                        scroll(app.LogTextArea, 'bottom');
                    catch ME
                        % Handle the case where there's an issue with 'L Fitted Slope (ml/s)'
                        confirm = msgbox('No valid slope data found. Please recalibrate.', 'Warning');
                        uiwait(confirm);
                        app.LogTextArea.Value{end+1} = 'No record found. You have to CALIBRATE!';
                        scroll(app.LogTextArea, 'bottom');
                    end
            end
        end

        % Button pushed function: PortButton
        function PortButtonPushed(app, event)
            app.PortButton.Enable = 'off';
            switch app.Info.port.state
                case 'withdraw' % withdraw -> approach
                    writePosition(app.Info.servo, app.Info.port.approach);
                    app.Info.port.state = 'approach';
                case 'approach' % approach -> withdraw
                    writePosition(app.Info.servo, app.Info.port.withdraw);
                    app.Info.port.state = 'withdraw';
            end
            % app.D.servo_angle = angle;
            app.PortButton.Enable = 'on';
        end

        % Button pushed function: WaterButton
        function WaterButtonPushed(app, event)
            % rewardTTLTime will only be updated during water calibration
            if isnumeric(app.CC.taskparam.waterTTLTime)
                app.WaterButton.Enable = 'off';
                writeDigitalPin(app.Info.ARDU, app.Info.PIN.water, 1);
                pause(app.CC.taskparam.waterTTLTime);
                writeDigitalPin(app.Info.ARDU, app.Info.PIN.water, 0);
                app.WaterButton.Enable = 'on';
            else
                app.LogTextArea.Value{end+1} =  '!!! Assign reward amount first by run calibration !!!';
                scroll(app.LogTextArea, 'bottom');
            end
        end

        % Value changed function: WatersizeLEditField
        function RewardsizemlEditFieldValueChanged(app, event)
            value = str2double(app.WatersizeLEditField.Value);
            app.CC.taskparam.waterSize = value;
            app.GNG.taskparam.waterSize = value;
            loadWaterTTL(app);
        end

        % Button pushed function: AirpuffButton
        function AirpuffButtonPushed(app, event)
            if app.CC.taskparam.airpuffDur == 0
                return;
            end
            writeDigitalPin(app.Info.ARDU, app.Info.PIN.airpuff, 1);
            pause(app.CC.taskparam.airpuffDur);
            writeDigitalPin(app.Info.ARDU, app.Info.PIN.airpuff, 0);
        end

        % Value changed function: RunSwitch
        function RunSwitchValueChanged(app, event)
            value = app.RunSwitch.Value; 
            switch value
                case 'Run'
                    flag = true;
                    %% Check various connections and selections
                    % Check Arduino connection
                    if ~isfield(app.Info,'ARDU')
                        app.LogTextArea.Value{end+1} = '!!!!! Connect to Aruino first !!!!!';
                        
                        scroll(app.LogTextArea, 'bottom');
                        flag = false;
                    end

                    % Assign the right parameters to the correponding task
                    if contains(app.Info.session.taskName, {'CC', 'random'})
                        app.Info.taskparam = app.CC.taskparam;
                    elseif contains(app.Info.session.taskName, 'GNG')
                        app.Info.taskparam = app.GNG.taskparam;
                    else
                        app.LogTextArea.Value{end+1} =  'Please select a valid task.';
                        scroll(app.LogTextArea, 'bottom');
                    end

                    % Check water amount assignment
                    if ~isnumeric(app.Info.taskparam.waterTTLTime)
                        app.LogTextArea.Value{end+1} =  '!!!!! Run calibration first !!!!!';
                        scroll(app.LogTextArea, 'bottom');
                        flag = false;
                    end

                    % Check task assignment
                    if ~isfield(app.Info.session,'taskName')
                        app.LogTextArea.Value{end+1} =  'Choose task type';
                        scroll(app.LogTextArea, 'bottom');
                        flag = false;
                    end
                    
                    %% GUI update
                    if flag
                        % Change Switch Lamp Color and Text
                        cla(app.bigPlot);
                        cla(app.smallPlot);
                        app.TaskstoppedLamp.Color = [0.39,0.83,0.07];
                        app.TaskstoppedLampLabel.Text = 'Task running';
                        app.TaskstoppedLampLabel.FontColor = [0.39,0.83,0.07];

                        trainOrRecord = questdlg('What session is this?', ...
                                     'Session type','Training','Recording','Training'); %questdlg(quest,dlgtitle,btn1,btn2,defbtn)
                        switch trainOrRecord
                            case 'Training'
                                app.Info.session.recording = 'no';
                                app.LogTextArea.Value{end+1} =  'Training session starting now!';
                                scroll(app.LogTextArea, 'bottom');
                            case 'Recording'
                                app.Info.session.recording = 'yes';
                                app.LogTextArea.Value{end+1} =  'Recording session starting now!';
                                scroll(app.LogTextArea, 'bottom');
                        end

                        % Get start time
                        app.Info.session.startTime = datetime('now','Format','HH:mm:ss');
                        tmp = char(app.Info.session.startTime);
                        app.StartEditField.Value =  tmp;

                        % Update log area
                        app.LogTextArea.Value{end+1} =  ...
                            ['Started ', app.Info.session.animalID, ' ', app.Info.session.taskName, ' at ', tmp];
                        scroll(app.LogTextArea, 'bottom');
                        disp(['Started ', app.Info.session.animalID, ' ', app.Info.session.taskName, ' at ', tmp])

                        % Port position initialization 
                        writePosition(app.Info.servo, app.Info.port.withdraw);
                        app.Info.port.state = 'withdraw';

                        %% Check TTL recording devices
                        if isfield(app.DAQ, 'ARDU') 
                            app.LogTextArea.Value{end+1} = 'ARDU TTL recording started for this session.';
                            scroll(app.LogTextArea, 'bottom');
        
                            app.TTLstatusLamp.Color = [0.39,0.83,0.07];
                            app.TTLstatusLampLabel.Text = 'DAQ recording';
                            app.TTLstatusLampLabel.FontColor = [0.39,0.83,0.07];
                        end
                           
                        if isfield(app.NIDAQ, 'channel_num')
                            if app.NIDAQ.recording
                                app.LogTextArea.Value{end+1} = 'NI TTL recording started for this session.';
                                scroll(app.LogTextArea, 'bottom');
        
                                start(app.NIDAQ_ai, "Continuous");
                                app.NIDAQRecordingTTLLamp.Color = [0.39,0.83,0.07];
                                % app.NIDAQRecordingTTLLampLabel.Text = 'NI DAQ recording';
                                % app.NIDAQRecordingTTLLampLabel.FontColor = [0.39,0.83,0.07];
                            else 
                                app.LogTextArea.Value{end+1} = 'NI DAQ not recording for this session.';
                                scroll(app.LogTextArea, 'bottom');
                            end
                        end

                        % if isfield(app, 'AD3')
                        % end

                        %% Start running the task, does not need to feed in DAQ info
                        task.(app.Info.session.taskName)(app, app.Info, app.Stim, app.Info.ARDU, app.DAQ.ARDU); 


                        %% When task is stopped
                        if isequal(app.RunSwitch.Value, 'Stop')
                            app.Info.session.endTime = datetime('now','Format','HH:mm:ss');
                            tmp = char(app.Info.session.endTime);
                            app.StopEditField.Value = tmp;

                            app.LogTextArea.Value{end+1} =  ...
                                ['Finished ', app.Info.session.animalID, ' ',app.Info.session.taskName, ' at ', tmp];
                            scroll(app.LogTextArea, 'bottom');

                            % total time
                            app.Info.session.totalTime = app.Info.session.endTime - app.Info.session.startTime;
                            tmp = char(app.Info.session.totalTime);
                            app.DurationEditField.Value = tmp;

                            app.LogTextArea.Value{end+1} =  ...
                                ['Total time is ', tmp];
                            scroll(app.LogTextArea, 'bottom');

                            app.TaskstoppedLamp.Color = [0.85,0.33,0.10];
                            app.TaskstoppedLampLabel.Text = 'Task stopped';
                            app.TaskstoppedLampLabel.FontColor = [0.85,0.33,0.10];

                            % Update DAQ related information
                            if isfield(app.DAQ, 'ARDU')
                                app.TTLstatusLamp.Color = [0.85,0.33,0.10];
                                app.TTLstatusLampLabel.Text = 'DAQ stopped';
                                app.TTLstatusLampLabel.FontColor = [0.85,0.33,0.10];
                            end

                            if isfield(app.NIDAQ, 'channel_num')
                                saveNIDaqTTL(app);
                            end

                        end
                    else
                        app.RunSwitch.Value = 'Stop';
                    end
                    
                case 'Stop' 
                    % get end time
                    app.Info.session.endTime = datetime('now','Format','HH:mm:ss');
                    tmp = char(app.Info.session.endTime);
                    app.StopEditField.Value = tmp;

                    app.LogTextArea.Value{end+1} =  ...
                        ['Finished ', app.Info.session.animalID, ' ',app.Info.session.taskName, ' at ', tmp];
                    scroll(app.LogTextArea, 'bottom');

                    % total time
                    app.Info.session.totalTime = app.Info.session.endTime - app.Info.session.startTime;
                    tmp = char(app.Info.session.totalTime);
                    app.DurationEditField.Value = tmp;

                    app.LogTextArea.Value{end+1} =  ...
                        ['Total time is ', tmp];
                    scroll(app.LogTextArea, 'bottom');
                    
                    app.TaskstoppedLamp.Color = [0.85,0.33,0.10];
                    app.TaskstoppedLampLabel.Text = 'Task stopped';
                    app.TaskstoppedLampLabel.FontColor = [0.85,0.33,0.10];

                    app.TTLstatusLamp.Color = [0.85,0.33,0.10];
                    app.TTLstatusLampLabel.Text = 'DAQ stopped';
                    app.TTLstatusLampLabel.FontColor = [0.85,0.33,0.10];
            end
        end

        % Button pushed function: ReSelectsavingfolderButton
        function ReSelectsavingfolderButtonPushed(app, event)
            selected_path = uigetdir('C:\', 'Please select the project folder!');
            app.Info.path.mother = selected_path;
%             selected_path   
            app.Info.path.task = fullfile(app.Info.path.mother, '+task');
%             tas = app.Info.path.task
            app.Info.path.waterCalibration = fullfile(app.Info.path.mother,'+waterCalibration');
            app.Info.path.waterCalibrationRecord = fullfile(app.Info.path.waterCalibration, 'waterCalibrationRecordEphys.xlsx');
            app.Info.path.data = fullfile(app.Info.path.mother, 'Data');
            app.Info.path.dataSession = 'undefined yet';

            task = dir(fullfile(app.Info.path.mother,'+task')); %%%%% where the task folder is defined
            task = task(contains({task.name}, {'.m'}));
            task = {task.name}; 

            for i = 1:length(task)
                task{i}(end-1:end) = [];
            end

            % display task options 
            app.TaskListBox.Items = task;

            % % %% Display available serial ports for both ARDU and daq ARDU
            % availableCOM = serialportlist("available");
            % loadAvailableSerialports(app, 'ControlARDUListBox', availableCOM);
            % app.Info.ARDU_COM = availableCOM(1);
            % loadAvailableSerialports(app, 'DAQARDUListBox', availableCOM);
            % app.DAQ.ARDU_COM = availableCOM(1);
        end

        % Value changed function: ADInputChannelsEditField
        function ADInputChannelsEditFieldValueChanged(app, event)
            value = str2double(app.ADInputChannelsEditField.Value);
            app.Info.daq.channels = value;
        end

        % Value changed function: AD3daqSwtich
        function AD3daqSwtichValueChanged(app, event)
            switch app.AD3daqSwtich.Value
                case 'Record'
                    app.Info.session.daqrec = true;
                    % app.LogTextArea.Value{end+1} = 'Recording TTL!';
                    % scroll(app.LogTextArea, 'bottom');
                case 'Test'
                    app.Info.session.daqrec = false;
                    % app.LogTextArea.Value{end+1} = 'Not recording TTL!';
                    % scroll(app.LogTextArea, 'bottom');
            end
        end

        % Value changed function: AD3DAQListBox
        function AD3DAQListBoxValueChanged(app, event)
            app.Info.daq_id = app.AD3DAQListBox.Value;            
        end

        % Button pushed function: ADdaqConnectButton
        function ADdaqConnectButtonPushed(app, event)
            % app.LogTextArea.Value{end+1} = 'Assigning DAQ...... Wait for DAQ connection!';
            % scroll(app.LogTextArea, 'bottom');
            % try
            %     app.ADInput = daq("ni"); % ai, analog input             
            %     app.ADInput.Rate = str2double(app.SampleRateHzEditField.Value);
            %     channels = app.Info.daq.channels;
            %     for i = 1:length(channels)
            %         % add an input channel to a data acquisition session or
            %         % device object
            %         % addinput(daqObject, deviceID, channelID, measurementType)
            %         addinput(app.ADInput, app.Info.daq_id, string(['ai',num2str(channels(i))]), "Voltage");
            %         % For class 'daq.interfaces.DataAcquisition', Channel
            %         % is a property
            %         app.ADInput.Channels(i).Range = [-str2double(app.RangeVEditField.Value) str2double(app.RangeVEditField.Value)];
            %         channelName = ['AI',num2str(channels(i)),'EditField'];
            %         app.Info.daq.channelNames{i} = app.(channelName).Value;
            %     end
            %     app.LogTextArea.Value{end+1} = 'DAQ connected!';
            %     scroll(app.LogTextArea, 'bottom');
            % catch 
            %     app.LogTextArea.Value{end+1} = '!!!!! DAQ connection error !!!!!';
            %     scroll(app.LogTextArea, 'bottom');
            % end
        end

        % Value changed function: PortDelaysEditField
        function PortDelaysEditFieldValueChanged(app, event)
            app.CC.taskparam.portDelay = str2double(app.PortDelaysEditField.Value);
            app.GNG.taskparam.portDelay = str2double(app.PortDelaysEditField.Value);
        end

        % Value changed function: CCUSDelaysEditField
        function CCUSDelaysEditFieldValueChanged(app, event)
            app.CC.taskparam.usDelay = str2double(app.CCUSDelaysEditField.Value);
        end

        % Value changed function: initTaskTypeSwitch
        function initTaskTypeSwitchValueChanged(app, event)
            app.Info.session.rule = app.initTaskTypeSwitch.Value;
        end

        % Button pushed function: ARDUdaqConnectButton
        function ARDUdaqConnectButtonPushed(app, event)
             if isfield(app.DAQ,'ARDU')                
                app.LogTextArea.Value{end+1} =  ['DAQ ', char(app.DAQ.ARDU_COM), ' ALREADY CONNECTED!! DELETING!!'];
                scroll(app.LogTextArea, 'bottom');

                delete(app.DAQ.ARDU);

                app.LogTextArea.Value{end+1} =  'DONE!';
                scroll(app.LogTextArea, 'bottom');
            end
                app.LogTextArea.Value{end+1} =  'Assigning DAQ arduino ... wait for connection!';
                scroll(app.LogTextArea, 'bottom');

                % connected through serial communication, not direct
                % control therefore don't need to specify library
                app.DAQ.ARDU = serialport(app.DAQ.ARDU_COM, app.DAQ.ARDU_baudRate, 'Timeout', 5); %5s
    
                tmp = char(app.DAQ.ARDU_COM);
                app.LogTextArea.Value{end+1} = char(['Connected to DAQ arduino ', tmp]);
                scroll(app.LogTextArea, 'bottom');
        end

        % Value changed function: DAQARDUListBox
        function DAQARDUListBoxValueChanged(app, event)
            app.DAQ.ARDU_COM = app.DAQARDUListBox.Value;
        end

        % Value changed function: ARDUdaqSwtich
        function ARDUdaqSwtichValueChanged(app, event)
            switch app.ARDUdaqSwtich.Value
                case 'Record'
                    app.Info.session.daqrec = true;
                case 'Test'
                    app.Info.session.daqrec = false;
            end
        end

        % Selection change function: DAQTab
        function DAQTabSelectionChanged(app, event)
          % still working on this
        end

        % Value changed function: BaudRateHzEditField
        function BaudRateHzEditFieldValueChanged(app, event)
            app.DAQ.ARDU_baudRate = str2double(app.BaudRateHzEditField.Value);
        end

        % Value changed function: LEDDaqEditField
        function LEDDaqEditFieldValueChanged(app, event)
            app.Info.PIN.LEDDaq = app.LEDDaqEditField.Value;
        end

        % Value changed function: MotorDaqEditField
        function MotorDaqEditFieldValueChanged(app, event)
            app.Info.PIN.motorDaq = app.MotorDaqEditField.Value;
        end

        % Value changed function: WaterDaqEditField
        function WaterDaqEditFieldValueChanged(app, event)
            app.Info.PIN.waterDaq = app.WaterDaqEditField.Value;
        end

        % Value changed function: AifpuffdaqEditField
        function AifpuffdaqEditFieldValueChanged(app, event)
            app.Info.PIN.airpuffDaq = app.AifpuffdaqEditField.Value;
        end

        % Value changed function: ReinforcerProbabilityEditField
        function ReinforcerProbabilityEditFieldValueChanged(app, event)
            value = app.ReinforcerProbabilityEditField.Value;
            app.CC.taskparam.USprobability = str2double(value);
            app.GNG.taskparam.reinforcerProb = str2double(value);
        end

        % Value changed function: MotorDirectionwilloverwriteSwitch
        function MotorDirectionwilloverwriteSwitchValueChanged(app, event)
            value = app.MotorDirectionwilloverwriteSwitch.Value;
            switch value
                case 'Fwd'
                    app.Info.port.dir = 'fwd';
                    app.Info.port.approach = 0.2;
                    app.Info.port.withdraw = 0.4;
                case 'Rev'
                    app.Info.port.dir = 'rev';
                    app.Info.port.approach = 0.4;
                    app.Info.port.withdraw = 0.2;
            end
        end

        % Value changed function: airpuffDuration
        function airpuffDurationValueChanged(app, event)
            value = str2double(app.airpuffDuration.Value);
            app.CC.taskparam.airpuffDur = value;
            app.GNG.taskparam.airpuffDur = value;
        end

        % Value changed function: StartLEDdursEditField
        function StartLEDdursEditFieldValueChanged(app, event)
            app.CC.taskparam.startCueDur = str2double(app.StartLEDdursEditField.Value);
            app.GNG.taskparam.startCueDur = str2double(app.StartLEDdursEditField.Value);
        end

        % Value changed function: GNGLickwindowsEditField
        function GNGLickwindowsEditFieldValueChanged(app, event)
            app.GNG.taskparam.lickWindow = str2double(app.GNGLickwindowsEditField.Value);
        end

        % Value changed function: GNGReinforcerdelaysEditField
        function GNGReinforcerdelaysEditFieldValueChanged(app, event)
            app.GNG.taskparam.reinforcerDelay = str2double(app.GNGReinforcerdelaysEditField.Value);
        end

        % Value changed function: GNGTimeoutsEditField
        function GNGTimeoutsEditFieldValueChanged(app, event)
            app.GNG.taskparam.timeout = str2double(app.GNGTimeoutsEditField.Value);
        end

        % Value changed function: CCAirpuffProb
        function CCAirpuffProbValueChanged(app, event)
            app.CC.taskparam.airpuffProbability = str2double(app.CCAirpuffProb.Value);
        end

        % Value changed function: TotaltrialNumEditField
        function TotaltrialNumEditFieldValueChanged(app, event)
            app.Info.session.prespecifiedTrialNum = str2double(app.TotaltrialNumEditField.Value);
        end

        % Value changed function: MotorconnectedCheckBox
        function MotorconnectedCheckBoxValueChanged(app, event)
            app.Info.session.motorConnection = app.MotorconnectedCheckBox.Value;
        end

        % Button pushed function: NIdaqConnectButton
        function NIdaqConnectButtonPushed(app, event)
            app.LogTextArea.Value{end+1} = 'Assigning NI DAQ...... Wait for DAQ connection!';
            scroll(app.LogTextArea, 'bottom');

            try
                % create thet NIDAQ object
                app.NIDAQ_ai = daq("ni"); 
                % modify directly on the NIDAQ object
                app.NIDAQ_ai.Rate = str2double(app.SampleRateHzEditField.Value);
                % modify the dict
                app.NIDAQ.channel_num = app.NIDAQInputChannelsEditField.Value; % "0:7"
                app.NIDAQ.id = app.NIDAQListBox.Value;
                channels = str2num(app.NIDAQ.channel_num); % [0 1 2 3 4 5 6 7]
                % addinput(daqObject, deviceID, channelID, measurementType：['Voltage', 'Digital'] )
                aiChannels = addinput(app.NIDAQ_ai, app.NIDAQ.id, channels, "Voltage");
                % ADD DIGITAL CHANNELS, digital can't record continuously
                % diChannels = addinput(ni, app.NIDAQ.id, "port0/line"+app.NIDAQ.channels, "Digital");

                % specifying channel range
                for i = 1:length(channels)
                    % modifying directly on the NIDAQ object
                    app.NIDAQ_ai.Channels(i).Range = [-str2double(app.NIRangeVEditField.Value) str2double(app.NIRangeVEditField.Value)];
                    aiChannelNames = ['AI', num2str(channels(i)), 'EditField'];
                    app.NIDAQ_ai.Channels(i).Name = app.(aiChannelNames).Value;
                    % modify the dict
                    app.NIDAQ.channelNames{i} = app.(aiChannelNames).Value;

                    % diChannelNames = ['P0', num2str(channels(i)), 'EditField'];
                    % diChannels(i).Name = app.(diChannelNames).Value;
                    % app.NIDAQ.di.channelNames{i} = app.(diChannelNames).Value;
                end

                app.LogTextArea.Value{end+1} = 'NI DAQ connected!';
                scroll(app.LogTextArea, 'bottom');
            catch 
                app.LogTextArea.Value{end+1} = '!!!!! NI DAQ connection error !!!!!';
                scroll(app.LogTextArea, 'bottom');
            end
        end

        % Value changed function: NIDAQInputChannelsEditField
        function NIDAQInputChannelsEditFieldValueChanged(app, event)
            app.NIDAQ.channels = app.NIDAQInputChannelsEditField.Value;
        end

        % Value changed function: NIDAQListBox
        function NIDAQListBoxValueChanged(app, event)
            app.NIDAQ.id = app.NIDAQListBox.Value;
        end

        % Value changed function: RecordingonNIDAQCheckBox
        function RecordingonNIDAQCheckBoxValueChanged(app, event)
            value = app.RecordingonNIDAQCheckBox.Value;
            if value
               if isfield(app.NIDAQ, 'channel_num')
                % if isfield(app, 'NIDAQ_ai') don't know why doesn't work
                    app.NIDAQ.recording = true;
                else
                    app.LogTextArea.Value{end+1} = '!!! Please connect to NIDAQ first !!!';
                    scroll(app.LogTextArea, "bottom");
                end
            else
                app.NIDAQ.recording = false;
            end
        end

        % Value changed function: ExperimenterDropDown
        function ExperimenterDropDownValueChanged(app, event)
            app.Info.session.experimenter = app.ExperimenterDropDown.Value;
            switch app.Info.session.experimenter
                case 'JB'
                    tmp = 'Jungwon';
                case 'ZW'
                    tmp = 'Ziyu';
                otherwise
                    tmp = [];
            end
            if isempty(tmp)
                app.LogTextArea.Value{end+1} = 'Experimenter name is invalid';
            else
                app.LogTextArea.Value{end+1} =  ['Hello ',tmp];
            end
            scroll(app.LogTextArea, 'bottom');
        end

        % Value changed function: AnimaltypeDropDown
        function AnimaltypeDropDownValueChanged(app, event)
           app.Info.session.animalType = app.AnimaltypeDropDown.Value;
            
        end

        % Value changed function: ExptypeDropDown
        function ExptypeDropDownValueChanged(app, event)
            app.Info.session.expType = app.ExptypeDropDown.Value;
        end

        % Value changed function: RigDropDown
        function RigDropDownValueChanged(app, event)
            value = app.RigDropDown.Value;
            if all(isstrprop(value, 'digit'))
                app.Info.session.rig = ['rig', value];
            else
                app.Info.session.rig = value;
            end

            app.LogTextArea.Value{end+1} = ['============ Chose RIG ', value, ' ============'];
            scroll(app.LogTextArea, 'bottom');

            switch value
                case {'1P', 'B', 'atlas', '3'}
                    app.Info.port.dir = 'rev';
                    app.Info.port.approach = 0.4;
                    app.Info.port.withdraw = 0.2;
                    app.LogTextArea.Value{end+1} = 'Port dirction is REVERSE';
                    scroll(app.LogTextArea, 'bottom');
                case {'2', '1', 'A'}
                    app.Info.port.dir = 'fwd';
                    app.Info.port.approach = 0.2;
                    app.Info.port.withdraw = 0.4;
                    app.LogTextArea.Value{end+1} = 'Port dirction is FORWARD';
                    scroll(app.LogTextArea, 'bottom');
            end
            loadWaterTTL(app)
            
        end

        % Button pushed function: FindNIButton
        function FindNIButtonPushed(app, event)
            %  %% NI DAQ settings
            % app.NIDAQ.channels = app.NIDAQInputChannelsEditField.Value;
            % app.NIDAQ.recording = app.RecordingonNIDAQCheckBox.Value;

            %% Display available NIDAQ without connecting to DAQ
            try
                availableDAQ = daqlist("ni");
                app.NIDAQListBox.Items = availableDAQ.DeviceID;
                app.NIDAQ.id = availableDAQ.DeviceID(1);
            catch
                 app.LogTextArea.Value{end+1} = 'No NIDAQ device found. Check connection!';
                    scroll(app.LogTextArea, 'bottom');
            end
        end

        % Value changed function: GNGVolumeEditField
        function GNGVolumeEditFieldValueChanged(app, event)
            app.GNG.taskparam.volume = str2num(app.GNGVolumeEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.smallPlot = uiaxes(app.UIFigure);
            title(app.smallPlot, 'Trial Type Overview')
            xlabel(app.smallPlot, 'Trial')
            ylabel(app.smallPlot, 'CS')
            zlabel(app.smallPlot, 'Z')
            app.bigPlot = uiaxes(app.UIFigure);
            title(app.bigPlot, 'Mouse Lick Rate Plot')
            xlabel(app.bigPlot, 'Trials')
            ylabel(app.bigPlot, 'Lickrate')
            zlabel(app.bigPlot, 'Z')
            app.ConnectButton_4 = uibutton(app.UIFigure, 'push');
            app.ConnectButton_4.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_4Pushed, true);
            app.ConnectButton_4.Text = 'Connect';
            app.WaterButton = uibutton(app.UIFigure, 'push');
            app.WaterButton.ButtonPushedFcn = createCallbackFcn(app, @WaterButtonPushed, true);
            app.WaterButton.Text = 'Water';
            app.AirpuffButton = uibutton(app.UIFigure, 'push');
            app.AirpuffButton.ButtonPushedFcn = createCallbackFcn(app, @AirpuffButtonPushed, true);
            app.AirpuffButton.Text = 'Air puff';
            app.TrialLabel = uilabel(app.UIFigure);
            app.TrialLabel.Text = 'Trial #';
            app.trialNum = uilabel(app.UIFigure);
            app.trialNum.Text = '0';
            app.RCSlicksLabel = uilabel(app.UIFigure);
            app.RCSlicksLabel.Text = 'R CS lick/s';
            app.RlickRate = uilabel(app.UIFigure);
            app.RlickRate.Text = '0';
            app.NCSlicksLabel = uilabel(app.UIFigure);
            app.NCSlicksLabel.Text = 'N CS lick/s';
            app.NlickRate = uilabel(app.UIFigure);
            app.NlickRate.Text = '0';
            app.AccuracyLabel = uilabel(app.UIFigure);
            app.AccuracyLabel.Text = 'Accuracy';
            app.accuracy = uilabel(app.UIFigure);
            app.accuracy.Text = '0';
            app.StateLabel = uilabel(app.UIFigure);
            app.StateLabel.Text = 'State';
            app.status = uilabel(app.UIFigure);
            app.status.Text = 'ITI';
            app.DprimeLabel = uilabel(app.UIFigure);
            app.DprimeLabel.Text = 'D-prime';
            app.dprime = uilabel(app.UIFigure);
            app.dprime.Text = '0';
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.UIFigure);
            app.LogTextArea.Value = {'Good day!'};
            app.StartEditFieldLabel = uilabel(app.UIFigure);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.UIFigure, 'text');
            app.StartEditField.Editable = 'off';
            app.StopEditFieldLabel = uilabel(app.UIFigure);
            app.StopEditFieldLabel.Text = 'Stop';
            app.StopEditField = uieditfield(app.UIFigure, 'text');
            app.StopEditField.Editable = 'off';
            app.DurationEditFieldLabel = uilabel(app.UIFigure);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.UIFigure, 'text');
            app.DurationEditField.Editable = 'off';
            app.GUItitle = uilabel(app.UIFigure);
            app.GUItitle.Text = {'Neuromodulation - Behavior '; 'Single Port'};
            app.ControlARDUListBoxLabel = uilabel(app.UIFigure);
            app.ControlARDUListBoxLabel.Text = {'Control'; 'ARDU'};
            app.ControlARDUListBox = uilistbox(app.UIFigure);
            app.ControlARDUListBox.ValueChangedFcn = createCallbackFcn(app, @ControlARDUListBoxValueChanged, true);
            app.TaskListBoxLabel = uilabel(app.UIFigure);
            app.TaskListBoxLabel.Text = 'Task';
            app.TaskListBox = uilistbox(app.UIFigure);
            app.TaskListBox.ValueChangedFcn = createCallbackFcn(app, @TaskListBoxValueChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.sessioninfoTab = uitab(app.TabGroup);
            app.sessioninfoTab.Title = 'session info';
            app.initTaskTypeSwitch = uiswitch(app.sessioninfoTab, 'slider');
            app.initTaskTypeSwitch.Items = {'H_GO', 'L_GO'};
            app.initTaskTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @initTaskTypeSwitchValueChanged, true);
            app.initTaskTypeSwitch.Value = 'H_GO';
            app.AnimalIDEditFieldLabel = uilabel(app.sessioninfoTab);
            app.AnimalIDEditFieldLabel.Text = 'Animal ID';
            app.AnimalIDEditField = uieditfield(app.sessioninfoTab, 'text');
            app.AnimalIDEditField.ValueChangedFcn = createCallbackFcn(app, @AnimalIDEditFieldValueChanged, true);
            app.AnimalIDEditField.Value = 'WA00';
            app.MotorDirectionwilloverwriteSwitchLabel = uilabel(app.sessioninfoTab);
            app.MotorDirectionwilloverwriteSwitchLabel.Text = 'Motor Direction (will overwrite)';
            app.MotorDirectionwilloverwriteSwitch = uiswitch(app.sessioninfoTab, 'slider');
            app.MotorDirectionwilloverwriteSwitch.Items = {'Fwd', 'Rev'};
            app.MotorDirectionwilloverwriteSwitch.ValueChangedFcn = createCallbackFcn(app, @MotorDirectionwilloverwriteSwitchValueChanged, true);
            app.MotorDirectionwilloverwriteSwitch.Value = 'Fwd';
            app.MotorconnectedCheckBox = uicheckbox(app.sessioninfoTab);
            app.MotorconnectedCheckBox.ValueChangedFcn = createCallbackFcn(app, @MotorconnectedCheckBoxValueChanged, true);
            app.MotorconnectedCheckBox.Text = ' Motor connected';
            app.MotorconnectedCheckBox.Value = true;
            app.RigDropDownLabel = uilabel(app.sessioninfoTab);
            app.RigDropDownLabel.Text = 'Rig';
            app.RigDropDown = uidropdown(app.sessioninfoTab);
            app.RigDropDown.Items = {'1', '2', '3', 'A', 'B', '1P', 'atlas'};
            app.RigDropDown.ValueChangedFcn = createCallbackFcn(app, @RigDropDownValueChanged, true);
            app.RigDropDown.Value = '1';
            app.ExperimenterDropDownLabel = uilabel(app.sessioninfoTab);
            app.ExperimenterDropDownLabel.Text = 'Experimenter';
            app.ExperimenterDropDown = uidropdown(app.sessioninfoTab);
            app.ExperimenterDropDown.Items = {'', 'JB', 'ZW'};
            app.ExperimenterDropDown.ValueChangedFcn = createCallbackFcn(app, @ExperimenterDropDownValueChanged, true);
            app.ExperimenterDropDown.Value = '';
            app.AnimaltypeDropDownLabel = uilabel(app.sessioninfoTab);
            app.AnimaltypeDropDownLabel.Text = 'Animal type';
            app.AnimaltypeDropDown = uidropdown(app.sessioninfoTab);
            app.AnimaltypeDropDown.Items = {'', 'Dat-cre', 'Dbh-cre', 'WT'};
            app.AnimaltypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AnimaltypeDropDownValueChanged, true);
            app.AnimaltypeDropDown.Value = '';
            app.ExptypeDropDownLabel = uilabel(app.sessioninfoTab);
            app.ExptypeDropDownLabel.Text = 'Exp. type';
            app.ExptypeDropDown = uidropdown(app.sessioninfoTab);
            app.ExptypeDropDown.Items = {'', 'Training', '1P imaging', '2P imaging', 'Ephys'};
            app.ExptypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ExptypeDropDownValueChanged, true);
            app.ExptypeDropDown.Value = '';
            app.RunSwitch = uiswitch(app.UIFigure, 'slider');
            app.RunSwitch.Items = {'Stop', 'Run'};
            app.RunSwitch.ValueChangedFcn = createCallbackFcn(app, @RunSwitchValueChanged, true);
            app.RunSwitch.Value = 'Stop';
            app.TaskstoppedLampLabel = uilabel(app.UIFigure);
            app.TaskstoppedLampLabel.Text = 'Task stopped';
            app.TaskstoppedLamp = uilamp(app.UIFigure);
            app.ReSelectsavingfolderButton = uibutton(app.UIFigure, 'push');
            app.ReSelectsavingfolderButton.ButtonPushedFcn = createCallbackFcn(app, @ReSelectsavingfolderButtonPushed, true);
            app.ReSelectsavingfolderButton.Text = {'Re/Select '; 'saving folder'};
            app.DAQTab = uitabgroup(app.UIFigure);
            app.DAQTab.SelectionChangedFcn = createCallbackFcn(app, @DAQTabSelectionChanged, true);
            app.taskparamTab = uitab(app.DAQTab);
            app.taskparamTab.Title = 'taskparam';
            app.StartLEDdursEditFieldLabel = uilabel(app.taskparamTab);
            app.StartLEDdursEditFieldLabel.Text = 'Start LED dur.(s)';
            app.StartLEDdursEditField = uieditfield(app.taskparamTab, 'text');
            app.StartLEDdursEditField.ValueChangedFcn = createCallbackFcn(app, @StartLEDdursEditFieldValueChanged, true);
            app.StartLEDdursEditField.Value = '0.5';
            app.ISIsEditFieldLabel = uilabel(app.taskparamTab);
            app.ISIsEditFieldLabel.Text = 'ISI (s)';
            app.ISIsEditField = uieditfield(app.taskparamTab, 'text');
            app.ISIsEditField.ValueChangedFcn = createCallbackFcn(app, @ISIsEditFieldValueChanged, true);
            app.ISIsEditField.Value = '0.5';
            app.StimdurationsEditFieldLabel = uilabel(app.taskparamTab);
            app.StimdurationsEditFieldLabel.Text = 'Stim duration (s)';
            app.StimdurationsEditField = uieditfield(app.taskparamTab, 'text');
            app.StimdurationsEditField.ValueChangedFcn = createCallbackFcn(app, @StimdurationsEditFieldValueChanged, true);
            app.StimdurationsEditField.Value = '0.5';
            app.RespdurationsEditFieldLabel = uilabel(app.taskparamTab);
            app.RespdurationsEditFieldLabel.Text = 'Resp duration (s)';
            app.RespdurationsEditField = uieditfield(app.taskparamTab, 'text');
            app.RespdurationsEditField.ValueChangedFcn = createCallbackFcn(app, @RespdurationsEditFieldValueChanged, true);
            app.RespdurationsEditField.Value = '2';
            app.ITIsEditFieldLabel = uilabel(app.taskparamTab);
            app.ITIsEditFieldLabel.Text = 'ITI (s)';
            app.ITIsEditField = uieditfield(app.taskparamTab, 'text');
            app.ITIsEditField.ValueChangedFcn = createCallbackFcn(app, @ITIsEditFieldValueChanged, true);
            app.ITIsEditField.Value = '1 2';
            app.USDelaysEditFieldLabel = uilabel(app.taskparamTab);
            app.USDelaysEditFieldLabel.Text = 'US Delay (s)';
            app.CCUSDelaysEditField = uieditfield(app.taskparamTab, 'text');
            app.CCUSDelaysEditField.ValueChangedFcn = createCallbackFcn(app, @CCUSDelaysEditFieldValueChanged, true);
            app.CCUSDelaysEditField.Value = '1';
            app.StimFreqkHzEditFieldLabel = uilabel(app.taskparamTab);
            app.StimFreqkHzEditFieldLabel.Text = 'Stim Freq (kHz)';
            app.StimFreqkHzEditField = uieditfield(app.taskparamTab, 'text');
            app.StimFreqkHzEditField.ValueChangedFcn = createCallbackFcn(app, @StimFreqkHzEditFieldValueChanged, true);
            app.StimFreqkHzEditField.Value = '4 12';
            app.WatersizeLEditFieldLabel = uilabel(app.taskparamTab);
            app.WatersizeLEditFieldLabel.Text = 'Water size (μL)';
            app.WatersizeLEditField = uieditfield(app.taskparamTab, 'text');
            app.WatersizeLEditField.ValueChangedFcn = createCallbackFcn(app, @RewardsizemlEditFieldValueChanged, true);
            app.WatersizeLEditField.Value = '3';
            app.ReinforcerprobLabel = uilabel(app.taskparamTab);
            app.ReinforcerprobLabel.Text = 'Reinforcer prob.';
            app.ReinforcerProbabilityEditField = uieditfield(app.taskparamTab, 'text');
            app.ReinforcerProbabilityEditField.ValueChangedFcn = createCallbackFcn(app, @ReinforcerProbabilityEditFieldValueChanged, true);
            app.ReinforcerProbabilityEditField.Value = '1';
            app.PortDelaysEditFieldLabel = uilabel(app.taskparamTab);
            app.PortDelaysEditFieldLabel.Text = 'Port Delay (s)';
            app.PortDelaysEditField = uieditfield(app.taskparamTab, 'text');
            app.PortDelaysEditField.ValueChangedFcn = createCallbackFcn(app, @PortDelaysEditFieldValueChanged, true);
            app.PortDelaysEditField.Value = '0.5';
            app.ClassicalConditioningLabel = uilabel(app.taskparamTab);
            app.ClassicalConditioningLabel.Text = 'Classical Conditioning';
            app.AirpuffdurationsLabel = uilabel(app.taskparamTab);
            app.AirpuffdurationsLabel.Text = 'Airpuff duration (s)';
            app.airpuffDuration = uieditfield(app.taskparamTab, 'text');
            app.airpuffDuration.ValueChangedFcn = createCallbackFcn(app, @airpuffDurationValueChanged, true);
            app.airpuffDuration.Value = '0.3';
            app.LickwindowsEditFieldLabel = uilabel(app.taskparamTab);
            app.LickwindowsEditFieldLabel.Text = 'Lick window (s)';
            app.GNGLickwindowsEditField = uieditfield(app.taskparamTab, 'text');
            app.GNGLickwindowsEditField.ValueChangedFcn = createCallbackFcn(app, @GNGLickwindowsEditFieldValueChanged, true);
            app.GNGLickwindowsEditField.Value = '0.8';
            app.ReinforcerdelaysEditFieldLabel = uilabel(app.taskparamTab);
            app.ReinforcerdelaysEditFieldLabel.Text = 'Reinforcer delay (s)';
            app.GNGReinforcerdelaysEditField = uieditfield(app.taskparamTab, 'text');
            app.GNGReinforcerdelaysEditField.ValueChangedFcn = createCallbackFcn(app, @GNGReinforcerdelaysEditFieldValueChanged, true);
            app.GNGReinforcerdelaysEditField.Value = '1';
            app.GONOGOLabel = uilabel(app.taskparamTab);
            app.GONOGOLabel.Text = 'GO/NOGO ';
            app.TimeoutsEditFieldLabel = uilabel(app.taskparamTab);
            app.TimeoutsEditFieldLabel.Text = 'Timeout (s)';
            app.GNGTimeoutsEditField = uieditfield(app.taskparamTab, 'text');
            app.GNGTimeoutsEditField.ValueChangedFcn = createCallbackFcn(app, @GNGTimeoutsEditFieldValueChanged, true);
            app.GNGTimeoutsEditField.Value = '3';
            app.AirpuffprobLabel = uilabel(app.taskparamTab);
            app.AirpuffprobLabel.Text = 'Airpuff prob.';
            app.CCAirpuffProb = uieditfield(app.taskparamTab, 'text');
            app.CCAirpuffProb.ValueChangedFcn = createCallbackFcn(app, @CCAirpuffProbValueChanged, true);
            app.CCAirpuffProb.Value = '0';
            app.TotaltrialNumEditFieldLabel = uilabel(app.taskparamTab);
            app.TotaltrialNumEditFieldLabel.Text = 'Total trial Num.';
            app.TotaltrialNumEditField = uieditfield(app.taskparamTab, 'text');
            app.TotaltrialNumEditField.ValueChangedFcn = createCallbackFcn(app, @TotaltrialNumEditFieldValueChanged, true);
            app.AirpuffprobLabel_2 = uilabel(app.taskparamTab);
            app.AirpuffprobLabel_2.Text = 'Block';
            app.CCAirpuffProb_2 = uieditfield(app.taskparamTab, 'text');
            app.VolumeLabel = uilabel(app.taskparamTab);
            app.VolumeLabel.Text = 'Volume';
            app.GNGVolumeEditField = uieditfield(app.taskparamTab, 'text');
            app.GNGVolumeEditField.ValueChangedFcn = createCallbackFcn(app, @GNGVolumeEditFieldValueChanged, true);
            app.GNGVolumeEditField.Value = '0.1 1 5 10';
            app.SharedLabel = uilabel(app.taskparamTab);
            app.SharedLabel.Text = 'Shared';
            app.DisconnectmotoraddtrialforrandomsessionLabel = uilabel(app.taskparamTab);
            app.DisconnectmotoraddtrialforrandomsessionLabel.Text = {'Disconnect motor & add '; 'trial # for random session'};
            app.NIDaqTab = uitab(app.DAQTab);
            app.NIDaqTab.Title = 'NI Daq';
            app.NIDAQPINsLabel = uilabel(app.NIDaqTab);
            app.NIDAQPINsLabel.Text = 'NI-DAQ PINs';
            app.NIdaqConnectButton = uibutton(app.NIDaqTab, 'push');
            app.NIdaqConnectButton.ButtonPushedFcn = createCallbackFcn(app, @NIdaqConnectButtonPushed, true);
            app.NIdaqConnectButton.Text = 'Connect NI';
            app.InputChannelsEditField_3Label = uilabel(app.NIDaqTab);
            app.InputChannelsEditField_3Label.Text = 'Input Channels';
            app.NIDAQInputChannelsEditField = uieditfield(app.NIDaqTab, 'text');
            app.NIDAQInputChannelsEditField.ValueChangedFcn = createCallbackFcn(app, @NIDAQInputChannelsEditFieldValueChanged, true);
            app.NIDAQInputChannelsEditField.Value = '0:7';
            app.SampleRateHzEditField_3Label = uilabel(app.NIDaqTab);
            app.SampleRateHzEditField_3Label.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField_3 = uieditfield(app.NIDaqTab, 'text');
            app.SampleRateHzEditField_3.Value = '20000';
            app.RangeVEditField_3Label = uilabel(app.NIDaqTab);
            app.RangeVEditField_3Label.Text = 'Range (V)';
            app.NIRangeVEditField = uieditfield(app.NIDaqTab, 'text');
            app.NIRangeVEditField.Value = '5';
            app.NotrecordingLabel = uilabel(app.NIDaqTab);
            app.NotrecordingLabel.Text = 'NIDAQ not recording';
            app.NIDAQRecordingTTLLamp = uilamp(app.NIDaqTab);
            app.AI0EditFieldLabel = uilabel(app.NIDaqTab);
            app.AI0EditFieldLabel.Text = 'AI0';
            app.AI0EditField = uieditfield(app.NIDaqTab, 'text');
            app.AI0EditField.Value = 'LED';
            app.AI1EditFieldLabel = uilabel(app.NIDaqTab);
            app.AI1EditFieldLabel.Text = 'AI1';
            app.AI1EditField = uieditfield(app.NIDaqTab, 'text');
            app.AI1EditField.Value = 'LED_arduDaq';
            app.AI2EditFieldLabel = uilabel(app.NIDaqTab);
            app.AI2EditFieldLabel.Text = 'AI2';
            app.AI2EditField = uieditfield(app.NIDaqTab, 'text');
            app.AI2EditField.Value = 'Reinforcer';
            app.AI3EditFieldLabel = uilabel(app.NIDaqTab);
            app.AI3EditFieldLabel.Text = 'AI3';
            app.AI3EditField = uieditfield(app.NIDaqTab, 'text');
            app.AI3EditField.Value = 'Motor';
            app.AI4EditFieldLabel = uilabel(app.NIDaqTab);
            app.AI4EditFieldLabel.Text = 'AI4';
            app.AI4EditField = uieditfield(app.NIDaqTab, 'text');
            app.AI4EditField.Value = 'Lick';
            app.AI5EditFieldLabel = uilabel(app.NIDaqTab);
            app.AI5EditFieldLabel.Text = 'AI5';
            app.AI5EditField = uieditfield(app.NIDaqTab, 'text');
            app.AI5EditField.Value = 'TIR';
            app.AI6EditFieldLabel = uilabel(app.NIDaqTab);
            app.AI6EditFieldLabel.Text = 'AI6';
            app.AI6EditField = uieditfield(app.NIDaqTab, 'text');
            app.AI6EditField.Value = 'Sound';
            app.AI7EditFieldLabel = uilabel(app.NIDaqTab);
            app.AI7EditFieldLabel.Text = 'AI7';
            app.AI7EditField = uieditfield(app.NIDaqTab, 'text');
            app.AI7EditField.Value = 'Cam_pupil';
            app.NIDAQLabel = uilabel(app.NIDaqTab);
            app.NIDAQLabel.Text = 'NI-DAQ';
            app.NIDAQListBox = uilistbox(app.NIDaqTab);
            app.NIDAQListBox.ValueChangedFcn = createCallbackFcn(app, @NIDAQListBoxValueChanged, true);
            app.RecordingonNIDAQCheckBox = uicheckbox(app.NIDaqTab);
            app.RecordingonNIDAQCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecordingonNIDAQCheckBoxValueChanged, true);
            app.RecordingonNIDAQCheckBox.Text = ' Recording on NI-DAQ?';
            app.FindNIButton = uibutton(app.NIDaqTab, 'push');
            app.FindNIButton.ButtonPushedFcn = createCallbackFcn(app, @FindNIButtonPushed, true);
            app.FindNIButton.Text = 'Find NI';
            app.NIDAQnotrecordingLabel = uilabel(app.NIDaqTab);
            app.NIDAQnotrecordingLabel.Text = 'NI DAQ Rec';
            app.NIDAQRecLamp = uilamp(app.NIDaqTab);
            app.pinandwatercalibrationTab = uitab(app.DAQTab);
            app.pinandwatercalibrationTab.Title = 'pin and water calibration';
            app.SoundDaqEditFieldLabel = uilabel(app.pinandwatercalibrationTab);
            app.SoundDaqEditFieldLabel.Text = 'Sound Daq';
            app.SoundDaqEditField = uieditfield(app.pinandwatercalibrationTab, 'text');
            app.SoundDaqEditField.ValueChangedFcn = createCallbackFcn(app, @SoundDaqEditFieldValueChanged, true);
            app.SoundDaqEditField.Value = 'D4';
            app.LickSensorInputEditFieldLabel = uilabel(app.pinandwatercalibrationTab);
            app.LickSensorInputEditFieldLabel.Text = 'Lick Sensor Input';
            app.LickSensorInputEditField = uieditfield(app.pinandwatercalibrationTab, 'text');
            app.LickSensorInputEditField.ValueChangedFcn = createCallbackFcn(app, @LickSensorInputEditFieldValueChanged, true);
            app.LickSensorInputEditField.Value = 'D12';
            app.WaterEditFieldLabel = uilabel(app.pinandwatercalibrationTab);
            app.WaterEditFieldLabel.Text = 'Water';
            app.WaterEditField = uieditfield(app.pinandwatercalibrationTab, 'text');
            app.WaterEditField.ValueChangedFcn = createCallbackFcn(app, @WaterEditFieldValueChanged, true);
            app.WaterEditField.Value = 'D7';
            app.MotorEditFieldLabel = uilabel(app.pinandwatercalibrationTab);
            app.MotorEditFieldLabel.Text = 'Motor';
            app.MotorEditField = uieditfield(app.pinandwatercalibrationTab, 'text');
            app.MotorEditField.ValueChangedFcn = createCallbackFcn(app, @MotorEditFieldValueChanged, true);
            app.MotorEditField.Value = 'D9';
            app.LEDEditFieldLabel = uilabel(app.pinandwatercalibrationTab);
            app.LEDEditFieldLabel.Text = 'LED';
            app.LEDEditField = uieditfield(app.pinandwatercalibrationTab, 'text');
            app.LEDEditField.ValueChangedFcn = createCallbackFcn(app, @LEDEditFieldValueChanged, true);
            app.LEDEditField.Value = 'D2';
            app.AirpuffEditFieldLabel = uilabel(app.pinandwatercalibrationTab);
            app.AirpuffEditFieldLabel.Text = 'Airpuff';
            app.AirpuffEditField = uieditfield(app.pinandwatercalibrationTab, 'text');
            app.AirpuffEditField.ValueChangedFcn = createCallbackFcn(app, @AirpuffEditFieldValueChanged, true);
            app.AirpuffEditField.Value = 'D5';
            app.LEDDaqEditFieldLabel = uilabel(app.pinandwatercalibrationTab);
            app.LEDDaqEditFieldLabel.Text = 'LED Daq';
            app.LEDDaqEditField = uieditfield(app.pinandwatercalibrationTab, 'text');
            app.LEDDaqEditField.ValueChangedFcn = createCallbackFcn(app, @LEDDaqEditFieldValueChanged, true);
            app.LEDDaqEditField.Value = 'D3';
            app.MotorDaqEditFieldLabel = uilabel(app.pinandwatercalibrationTab);
            app.MotorDaqEditFieldLabel.Text = 'Motor Daq';
            app.MotorDaqEditField = uieditfield(app.pinandwatercalibrationTab, 'text');
            app.MotorDaqEditField.ValueChangedFcn = createCallbackFcn(app, @MotorDaqEditFieldValueChanged, true);
            app.MotorDaqEditField.Value = 'D11';
            app.WaterDaqEditFieldLabel = uilabel(app.pinandwatercalibrationTab);
            app.WaterDaqEditFieldLabel.Text = 'Water Daq';
            app.WaterDaqEditField = uieditfield(app.pinandwatercalibrationTab, 'text');
            app.WaterDaqEditField.ValueChangedFcn = createCallbackFcn(app, @WaterDaqEditFieldValueChanged, true);
            app.WaterDaqEditField.Value = 'D8';
            app.AifpuffdaqEditFieldLabel = uilabel(app.pinandwatercalibrationTab);
            app.AifpuffdaqEditFieldLabel.Text = 'Aifpuff daq';
            app.AifpuffdaqEditField = uieditfield(app.pinandwatercalibrationTab, 'text');
            app.AifpuffdaqEditField.ValueChangedFcn = createCallbackFcn(app, @AifpuffdaqEditFieldValueChanged, true);
            app.AifpuffdaqEditField.Value = 'D6';
            app.RunCalibrationButton = uibutton(app.pinandwatercalibrationTab, 'push');
            app.RunCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @RunCalibrationButtonPushed, true);
            app.RunCalibrationButton.Text = 'Run Calibration';
            app.ValveOpensEditFieldLabel = uilabel(app.pinandwatercalibrationTab);
            app.ValveOpensEditFieldLabel.Text = 'Valve Open (s)';
            app.ValveOpensEditField = uieditfield(app.pinandwatercalibrationTab, 'text');
            app.ValveOpensEditField.ValueChangedFcn = createCallbackFcn(app, @ValveOpensEditFieldValueChanged, true);
            app.ValveOpensEditField.Value = '0.1 0.2 0.5';
            app.ofdatapointsEditFieldLabel = uilabel(app.pinandwatercalibrationTab);
            app.ofdatapointsEditFieldLabel.Text = '# of datapoints';
            app.ofdatapointsEditField = uieditfield(app.pinandwatercalibrationTab, 'text');
            app.ofdatapointsEditField.ValueChangedFcn = createCallbackFcn(app, @ofdatapointsEditFieldValueChanged, true);
            app.ofdatapointsEditField.Value = '30';
            app.WatercalibrationLabel = uilabel(app.pinandwatercalibrationTab);
            app.WatercalibrationLabel.Text = 'Water calibration';
            app.ControlARDUPinInfoifchangehavetomodifyondeviceLabel = uilabel(app.pinandwatercalibrationTab);
            app.ControlARDUPinInfoifchangehavetomodifyondeviceLabel.Text = {'Control ARDU Pin Info '; '(if change have to modify on device)'};
            app.Cam_pupilTab = uitab(app.DAQTab);
            app.Cam_pupilTab.Title = 'Cam_pupil';
            app.ARDUDaqInfoTab = uitab(app.DAQTab);
            app.ARDUDaqInfoTab.Title = 'ARDU Daq Info';
            app.ArduinoDigitalIOPINsifchangehavetomodifyondeviceLabel = uilabel(app.ARDUDaqInfoTab);
            app.ArduinoDigitalIOPINsifchangehavetomodifyondeviceLabel.Text = {'Arduino Digital I/O PINs '; '(if change have to modify on device)'};
            app.InputChannelsEditField_2Label = uilabel(app.ARDUDaqInfoTab);
            app.InputChannelsEditField_2Label.Text = 'Input Channels';
            app.ARDUDAQInputChannelsEditField = uieditfield(app.ARDUDaqInfoTab, 'text');
            app.ARDUDAQInputChannelsEditField.Value = '2:13';
            app.BaudRateHzEditFieldLabel = uilabel(app.ARDUDaqInfoTab);
            app.BaudRateHzEditFieldLabel.Text = 'Baud Rate (Hz)';
            app.BaudRateHzEditField = uieditfield(app.ARDUDaqInfoTab, 'text');
            app.BaudRateHzEditField.ValueChangedFcn = createCallbackFcn(app, @BaudRateHzEditFieldValueChanged, true);
            app.BaudRateHzEditField.Value = '9600';
            app.Label_7 = uilabel(app.ARDUDaqInfoTab);
            app.Label_7.Text = '8';
            app.EditField_9 = uieditfield(app.ARDUDaqInfoTab, 'text');
            app.EditField_9.Value = 'WATER';
            app.EditField_11Label = uilabel(app.ARDUDaqInfoTab);
            app.EditField_11Label.Text = '2';
            app.EditField_11 = uieditfield(app.ARDUDaqInfoTab, 'text');
            app.EditField_11.Value = 'LICK';
            app.EditField_12Label = uilabel(app.ARDUDaqInfoTab);
            app.EditField_12Label.Text = '3';
            app.EditField_12 = uieditfield(app.ARDUDaqInfoTab, 'text');
            app.EditField_12.Value = 'LED';
            app.EditField_13Label = uilabel(app.ARDUDaqInfoTab);
            app.EditField_13Label.Text = '4';
            app.EditField_13 = uieditfield(app.ARDUDaqInfoTab, 'text');
            app.EditField_13.Value = 'SOUND';
            app.EditField_14Label = uilabel(app.ARDUDaqInfoTab);
            app.EditField_14Label.Text = '5';
            app.EditField_14 = uieditfield(app.ARDUDaqInfoTab, 'text');
            app.EditField_15Label = uilabel(app.ARDUDaqInfoTab);
            app.EditField_15Label.Text = '6';
            app.EditField_15 = uieditfield(app.ARDUDaqInfoTab, 'text');
            app.EditField_15.Value = 'AIRPUFF';
            app.EditField_16Label = uilabel(app.ARDUDaqInfoTab);
            app.EditField_16Label.Text = '7';
            app.EditField_16 = uieditfield(app.ARDUDaqInfoTab, 'text');
            app.Label_11 = uilabel(app.ARDUDaqInfoTab);
            app.Label_11.Text = '12';
            app.EditField_17 = uieditfield(app.ARDUDaqInfoTab, 'text');
            app.Label_12 = uilabel(app.ARDUDaqInfoTab);
            app.Label_12.Text = '13';
            app.EditField_18 = uieditfield(app.ARDUDaqInfoTab, 'text');
            app.Label_9 = uilabel(app.ARDUDaqInfoTab);
            app.Label_9.Text = '10';
            app.EditField_19 = uieditfield(app.ARDUDaqInfoTab, 'text');
            app.Label_10 = uilabel(app.ARDUDaqInfoTab);
            app.Label_10.Text = '11';
            app.EditField_20 = uieditfield(app.ARDUDaqInfoTab, 'text');
            app.EditField_20.Value = 'PORT';
            app.ARDUdaqSwtich = uiswitch(app.ARDUDaqInfoTab, 'slider');
            app.ARDUdaqSwtich.Items = {'Record', 'Test'};
            app.ARDUdaqSwtich.ValueChangedFcn = createCallbackFcn(app, @ARDUdaqSwtichValueChanged, true);
            app.ARDUdaqSwtich.Value = 'Record';
            app.AD3DaqTab = uitab(app.DAQTab);
            app.AD3DaqTab.Title = 'AD3 Daq';
            app.InputChannelsEditFieldLabel = uilabel(app.AD3DaqTab);
            app.InputChannelsEditFieldLabel.Text = 'Input Channels';
            app.ADInputChannelsEditField = uieditfield(app.AD3DaqTab, 'text');
            app.ADInputChannelsEditField.ValueChangedFcn = createCallbackFcn(app, @ADInputChannelsEditFieldValueChanged, true);
            app.ADInputChannelsEditField.Value = '0:7';
            app.SampRateHzLabel = uilabel(app.AD3DaqTab);
            app.SampRateHzLabel.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField = uieditfield(app.AD3DaqTab, 'text');
            app.SampleRateHzEditField.Value = '20000';
            app.RangeVLabel = uilabel(app.AD3DaqTab);
            app.RangeVLabel.Text = 'Range (V)';
            app.RangeVEditField = uieditfield(app.AD3DaqTab, 'text');
            app.RangeVEditField.Value = '5';
            app.RecordingTTLLampLabel = uilabel(app.AD3DaqTab);
            app.RecordingTTLLampLabel.Text = 'Recording TTL';
            app.RecordingTTLLamp = uilamp(app.AD3DaqTab);
            app.EditFieldLabel = uilabel(app.AD3DaqTab);
            app.EditFieldLabel.Text = '0';
            app.EditField = uieditfield(app.AD3DaqTab, 'text');
            app.EditField.Value = 'LED_on';
            app.EditField_2Label = uilabel(app.AD3DaqTab);
            app.EditField_2Label.Text = '1';
            app.EditField_2 = uieditfield(app.AD3DaqTab, 'text');
            app.EditField_2.Value = 'Cue_on';
            app.EditField_3Label = uilabel(app.AD3DaqTab);
            app.EditField_3Label.Text = '2';
            app.EditField_3 = uieditfield(app.AD3DaqTab, 'text');
            app.EditField_3.Value = 'Port_on';
            app.EditField_4Label = uilabel(app.AD3DaqTab);
            app.EditField_4Label.Text = '3';
            app.EditField_4 = uieditfield(app.AD3DaqTab, 'text');
            app.EditField_4.Value = 'Lick';
            app.EditField_5Label = uilabel(app.AD3DaqTab);
            app.EditField_5Label.Text = '4';
            app.EditField_5 = uieditfield(app.AD3DaqTab, 'text');
            app.EditField_5.Value = 'Airpuff';
            app.EditField_6Label = uilabel(app.AD3DaqTab);
            app.EditField_6Label.Text = '5';
            app.EditField_6 = uieditfield(app.AD3DaqTab, 'text');
            app.EditField_6.Value = 'Water_on';
            app.EditField_7Label = uilabel(app.AD3DaqTab);
            app.EditField_7Label.Text = '6';
            app.EditField_7 = uieditfield(app.AD3DaqTab, 'text');
            app.EditField_7.Value = 'Cam_face';
            app.EditField_8Label = uilabel(app.AD3DaqTab);
            app.EditField_8Label.Text = '7';
            app.EditField_8 = uieditfield(app.AD3DaqTab, 'text');
            app.EditField_8.Value = 'Cam_tougue';
            app.AD3DigitalPINsLabel = uilabel(app.AD3DaqTab);
            app.AD3DigitalPINsLabel.Text = 'AD3 Digital PINs';
            app.AD3daqSwtich = uiswitch(app.AD3DaqTab, 'slider');
            app.AD3daqSwtich.Items = {'Test', 'Record'};
            app.AD3daqSwtich.ValueChangedFcn = createCallbackFcn(app, @AD3daqSwtichValueChanged, true);
            app.AD3daqSwtich.Value = 'Test';
            app.ADdaqConnectButton = uibutton(app.AD3DaqTab, 'push');
            app.ADdaqConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ADdaqConnectButtonPushed, true);
            app.ADdaqConnectButton.Text = 'Connect';
            app.DAQListBoxLabel = uilabel(app.AD3DaqTab);
            app.DAQListBoxLabel.Text = 'DAQ';
            app.AD3DAQListBox = uilistbox(app.AD3DaqTab);
            app.AD3DAQListBox.ValueChangedFcn = createCallbackFcn(app, @AD3DAQListBoxValueChanged, true);
            app.SparepartsTab = uitab(app.DAQTab);
            app.SparepartsTab.Title = 'Spare parts';
            app.P00EditFieldLabel = uilabel(app.SparepartsTab);
            app.P00EditFieldLabel.Text = 'P0.0';
            app.P00EditField = uieditfield(app.SparepartsTab, 'text');
            app.P00EditField.Value = 'LED_daq';
            app.P01EditFieldLabel = uilabel(app.SparepartsTab);
            app.P01EditFieldLabel.Text = 'P0.1';
            app.P01EditField = uieditfield(app.SparepartsTab, 'text');
            app.P01EditField.Value = 'Airpuff_daq';
            app.P02EditFieldLabel = uilabel(app.SparepartsTab);
            app.P02EditFieldLabel.Text = 'P0.2';
            app.P02EditField = uieditfield(app.SparepartsTab, 'text');
            app.P02EditField.Value = 'Water_daq';
            app.P03EditFieldLabel = uilabel(app.SparepartsTab);
            app.P03EditFieldLabel.Text = 'P0.3';
            app.P03EditField = uieditfield(app.SparepartsTab, 'text');
            app.P03EditField.Value = 'Motor_daq';
            app.P04EditFieldLabel = uilabel(app.SparepartsTab);
            app.P04EditFieldLabel.Text = 'P0.4';
            app.P04EditField = uieditfield(app.SparepartsTab, 'text');
            app.P04EditField.Value = 'Lick_daq';
            app.P05EditFieldLabel = uilabel(app.SparepartsTab);
            app.P05EditFieldLabel.Text = 'P0.5';
            app.P05EditField = uieditfield(app.SparepartsTab, 'text');
            app.P05EditField.Value = 'Sound_daq';
            app.P06EditFieldLabel = uilabel(app.SparepartsTab);
            app.P06EditFieldLabel.Text = 'P0.6';
            app.P06EditField = uieditfield(app.SparepartsTab, 'text');
            app.P07EditFieldLabel = uilabel(app.SparepartsTab);
            app.P07EditFieldLabel.Text = 'P0.7';
            app.P07EditField = uieditfield(app.SparepartsTab, 'text');
            app.RigEditFieldLabel = uilabel(app.SparepartsTab);
            app.RigEditFieldLabel.Text = 'Rig';
            app.RigEditField = uieditfield(app.SparepartsTab, 'text');
            app.RigEditField.Value = '1';
            app.ExperimenterEditField = uieditfield(app.SparepartsTab, 'text');
            app.ExperimenterEditField.ValueChangedFcn = createCallbackFcn(app, @ExperimenterEditFieldValueChanged, true);
            app.ExperimenterEditFieldLabel = uilabel(app.SparepartsTab);
            app.ExperimenterEditFieldLabel.Text = 'Experimenter';
            app.AnimaltypeEditFieldLabel = uilabel(app.SparepartsTab);
            app.AnimaltypeEditFieldLabel.Text = 'Animal type';
            app.AnimaltypeEditField = uieditfield(app.SparepartsTab, 'text');
            app.AnimaltypeEditField.ValueChangedFcn = createCallbackFcn(app, @AnimaltypeEditFieldValueChanged, true);
            app.ExptypeEditFieldLabel = uilabel(app.SparepartsTab);
            app.ExptypeEditFieldLabel.Text = 'Exp. type';
            app.ExptypeEditField = uieditfield(app.SparepartsTab, 'text');
            app.ExptypeEditField.ValueChangedFcn = createCallbackFcn(app, @ExptypeEditFieldValueChanged, true);
            app.RepetitionLabel = uilabel(app.UIFigure);
            app.RepetitionLabel.Text = 'Repetition';
            app.repSwitch = uilabel(app.UIFigure);
            app.repSwitch.Text = '0';
            app.ARDUdaqConnectButton = uibutton(app.UIFigure, 'push');
            app.ARDUdaqConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ARDUdaqConnectButtonPushed, true);
            app.ARDUdaqConnectButton.Text = 'Re/Connect';
            app.ARDUDAQLabel = uilabel(app.UIFigure);
            app.ARDUDAQLabel.Text = {'DAQ'; 'ARDU'};
            app.DAQARDUListBox = uilistbox(app.UIFigure);
            app.DAQARDUListBox.ValueChangedFcn = createCallbackFcn(app, @DAQARDUListBoxValueChanged, true);
            app.TTLstatusLampLabel = uilabel(app.UIFigure);
            app.TTLstatusLampLabel.Text = 'TTL status';
            app.TTLstatusLamp = uilamp(app.UIFigure);
            app.PortButton = uibutton(app.UIFigure, 'push');
            app.PortButton.ButtonPushedFcn = createCallbackFcn(app, @PortButtonPushed, true);
            app.PortButton.Text = 'Port';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = movable_port_arduDAQ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end